classdef RocketApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RocketAppUIFigure               matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        ExportPropellantMenu            matlab.ui.container.Menu
        ExportEngineMenu                matlab.ui.container.Menu
        OpenMenu                        matlab.ui.container.Menu
        ImportPropellantMenu            matlab.ui.container.Menu
        ImportEngineMenu                matlab.ui.container.Menu
        MainLayout                      matlab.ui.container.GridLayout
        MainTabGroup                    matlab.ui.container.TabGroup
        InputsTab                       matlab.ui.container.Tab
        InputsGridLayout                matlab.ui.container.GridLayout
        EngineNameEditField             matlab.ui.control.EditField
        EngineNameLabel                 matlab.ui.control.Label
        ProblemDefinitionPanel          matlab.ui.container.Panel
        ProblemDefinitionGridLayout     matlab.ui.container.GridLayout
        SearchedindicatorDropDown       matlab.ui.control.DropDown
        SearchedindicatorDropDownLabel  matlab.ui.control.Label
        ProblemtypeButtonGroup          matlab.ui.container.ButtonGroup
        FindOptimalMixtureButton        matlab.ui.control.RadioButton
        FindOptimalNozzleButton         matlab.ui.control.RadioButton
        EngineAnalysisButton            matlab.ui.control.RadioButton
        NumberofiterationsSpinner       matlab.ui.control.Spinner
        NumberofiterationsSpinnerLabel  matlab.ui.control.Label
        FractiontypeDropDown            matlab.ui.control.DropDown
        FractiontypeDropDownLabel       matlab.ui.control.Label
        PropellantsourceDropDown        matlab.ui.control.DropDown
        PropellantsourceDropDownLabel   matlab.ui.control.Label
        ProblemsettingsLabel            matlab.ui.control.Label
        RunanalysisButton               matlab.ui.control.Button
        EnginedefinitionPanel           matlab.ui.container.Panel
        EngineDefinitionGridLayout      matlab.ui.container.GridLayout
        InjectionLabel                  matlab.ui.control.Label
        InjectionPressureEditFieldWithUnit  EditFieldWithUnit
        InjectionTemperatureEditFieldWithUnit  EditFieldWithUnit
        ThrustEditFieldWithUnit         EditFieldWithUnit
        ChamberLabel                    matlab.ui.control.Label
        ContractionRatioEditFieldWithUnit  EditFieldWithUnit
        MaxChamberTemperatureEditFieldWithUnit  EditFieldWithUnit
        MaxThroatTemperatureEditFieldWithUnit  EditFieldWithUnit
        HeatPowerEditFieldWithUnit      EditFieldWithUnit
        NozzleLabel                     matlab.ui.control.Label
        ExpansionRatioEditFieldWithUnit  EditFieldWithUnit
        SeparationOffsetEditFieldWithUnit  EditFieldWithUnit
        AtmosphereLabel                 matlab.ui.control.Label
        AtmosphericPressureEditFieldWithUnit  EditFieldWithUnit
        PropellantsPanel                matlab.ui.container.Panel
        PropellantsGridLayout           matlab.ui.container.GridLayout
        PropellantsEditableTable        EditableTable
        PropellantSpeciesPanel          matlab.ui.container.Panel
        PropellantSpeciesGridLayout     matlab.ui.container.GridLayout
        SpeciesEditableTable            EditableTable
        OutputsTab                      matlab.ui.container.Tab
        OutputsGridLayout               matlab.ui.container.GridLayout
        OutputsTabGroup                 matlab.ui.container.TabGroup
        SummaryTab                      matlab.ui.container.Tab
        SummaryGridLayout               matlab.ui.container.GridLayout
        ConstantinputsPanel             matlab.ui.container.Panel
        ConstantinputsLayout            matlab.ui.container.GridLayout
        ConstantinputsUITable           matlab.ui.control.Table
        GeneralPanel                    matlab.ui.container.Panel
        GeneralGridLayout               matlab.ui.container.GridLayout
        GeneralUITable                  matlab.ui.control.Table
        StationsTab                     matlab.ui.container.Tab
        StationsGridLayout              matlab.ui.container.GridLayout
        StationDropDown                 matlab.ui.control.DropDown
        StationDropDownLabel            matlab.ui.control.Label
        ChemicalspeciesPanel            matlab.ui.container.Panel
        ChemicalspeciesGridLayout       matlab.ui.container.GridLayout
        SpeciespropertyDropDown         matlab.ui.control.DropDown
        SpeciespropertyDropDownLabel    matlab.ui.control.Label
        ChemicalspeciesUITable          matlab.ui.control.Table
        ThermodynamicsPanel             matlab.ui.container.Panel
        ThermodynamicsGridLayout        matlab.ui.container.GridLayout
        ThermodynamicsUITable           matlab.ui.control.Table
    end

    properties (Access = private)
        problemType = Enum("Engine Analysis", "Optimal Nozzle", "Optimal Mixture");
        engineName;
        rangeableElementArray;

        nIterations;
        rangedPropertyName = "none";
        rangedInputValue;
        rangedIndex = 0;
        rangeablePropertyValueArray;
        rangeablePropertyNameArray;
        propellantArray State;
        gas;
        atmo;
        mass_mole;
        searchedIndicator;

        solverInputs;
        solverOutputs;
        

        inputNameArray = ["ratios", "expansionRatio" , "contractionRatio", "thrust" ...
            "pressure_injection", "temperature_injection", "heatMass", "temperature_chamber_max", "temperature_throat_max", "pressure_atmo", "separationTolerance" ...
            "mass_mole", "searchedIndicator"];

        inputUINameArray = ["Propellant ratios", "Expansion ratio" , "Contraction ratio", "thrust" ...
            "Injection pressure", "Injection temperature", "Head supplied", "Max chamber temperature", "Max throat temperature", "Atmospheric pressure", "Separation tolerance" ...
            "Fraction type", "Searched indicator"];

        generalOutputNameArray = ["flowState", "effectiveExhaustVelocity", "specificImpulse", "thrustCoefficient", "characteristicVelocity", "heatPower", "massFlow" ...
            "hasCondensation", "shockPosition" ...
            "optimalRatio", "pressure_ideal", "pressure_shockAtExit", "pressure_shockAtThroat", "pressure_separation" ...
            "expansionRatio_optimal", "expansionRatio_separation", "expansionRatio_shockAtThroat", "expansionRatio_shockAtExit_1", "expansionRatio_shockAtExit_2" ...
            "D_chamber", "D_throat", "D_exit"];

        generalOutputUINameArray = ["Flow state", "Specific Impulse [m/s]", "Specific Impulse [s]", "Thrust coefficient", "Characteristic velocity", "Supplied power", "Mass flow" ...
            "Condensation possible", "Shock position [A/A*]" ...
            "Optimal mixture ratio", "Ideal expansion ambient pressure", "Shock at exit ambient pressure", "Shock at throat ambient pressure", "Separation ambient pressure" ...
            "Optimal expansion ratio", "Separation expansion ratio", "Shock at throat expansion ratio", "Shock at exit 1 expansion ratio", "Shock at exit 2 expansion ratio" ...
            "Chamber diameter", "Throat Diameter", "Nozzle diameter"];

        stationNameArray = ["s_injection", "s_chamber", "s_throat", "s_exit", "s_shock_1", "s_shock_2"];

        stationUINameArray = ["Injection", "Chamber", "Throat", "Exit", "Before the shock", "After the shock"];

        stationOutputNameArray = ["temperature", "pressure", "density" ...
            "enthalpy", "intEnergy", "entropy", "gibbs", "totEnergy" ...
            "velocity", "Mach", "soundspeed", "massFlowFlux", "areaRatio" ...
            "cp", "cv", "k", "R_specific", "meanMolecularWeight", "hasCondensation"];

        stationOutputUINameArray = ["Temperature", "Pressure", "Density" ...
            "Enthalpy", "Internal Energy", "Entropy", "Gibbs energy", "Total energy" ...
            "Velocity", "Mach number", "Speed of sound", "Mass flux", "Area ratio" ...
            "Specific heat (p=const)", "Specific heat (V=const)", "Isentropic exponent", "Specific gas constant", "Mean molecular weight", "Condensation possible"];

        speciesOutputNameArray = ["speciesNames", "massFractions", "moleFractions", "molecularWeights", "chemPotentials"];

        speciesOutputUINameArray = ["Species", "Mass fractions", "Mole fractions", "Molecular weights", "Chemical potentials"];

        engineAnalysisAllowedInputNameArray = ["ratios", "expansionRatio" , "contractionRatio", "thrust" ...
            "pressure_injection", "temperature_injection", "heatMass", "temperature_chamber_max", "temperature_throat_max", "pressure_atmo", "separationTolerance" ...
            "mass_mole"];

        optimalNozzleAllowedInputNameArray = ["ratios", "contractionRatio", "thrust" ...
            "pressure_injection", "temperature_injection", "heatMass", "temperature_chamber_max", "temperature_throat_max", "pressure_atmo", "separationTolerance" ...
            "mass_mole"];

        optimalMixtureAllowedInputNameArray = ["ratios", "expansionRatio" , "contractionRatio", "thrust" ...
            "pressure_injection", "temperature_injection", "heatMass", "temperature_chamber_max", "temperature_throat_max", "pressure_atmo", ...
            "mass_mole", "searchedIndicator"];

        unitArray;
        propellantSourceArray;
        propellantSource;
        canRun

        speciesNameArray;
        speciesRatioArray;
    end

    methods (Access = private)

        function updateGeneralUITable(app)
            if app.rangedPropertyName == "none"
                nIterations = 1;
            else
                nIterations = app.nIterations;
            end

            app.GeneralUITable.Data = [];
            app.GeneralUITable.ColumnName = 1 : nIterations;
            
            for i = 1 : length(app.generalOutputNameArray)
                propertyName = app.generalOutputNameArray(i);
                propertyUIName = app.generalOutputUINameArray(i);
                app.GeneralUITable.RowName{i} = propertyUIName;
                isRowEmpty(i) = true;
                for j = 1 : nIterations
                    data = app.solverOutputs(j).(propertyName);
                    if class(data) == "logical"
                        if data == true
                            data = 'Yes';
                        else
                            data = 'No';
                        end
                    else
                        data = num2str(data);
                    end
                    if isRowEmpty(i)
                        if ~isempty(data)
                            isRowEmpty(i) = false;
                        end
                    end
                    app.GeneralUITable.Data{i, j} = data;
                end
            end
            rangedInputValue = string.empty;
            if app.rangedPropertyName ~= "none"
                if app.rangedPropertyName ~= "ratios"
                    rangedPropertyUIName = app.inputUINameArray(app.inputNameArray == app.rangedPropertyName);
                    rangedInputValue = app.rangedInputValue;
                else
                    rangedPropertyUIName = app.PropellantsEditableTable.nameColumn(app.rangedIndex) + " ratio";
                    rangedInputValue = app.rangedInputValue(app.rangedIndex, :);
                end
                app.GeneralUITable.RowName = [{rangedPropertyUIName}; app.GeneralUITable.RowName(~isRowEmpty)];
                app.GeneralUITable.Data = [num2cell(rangedInputValue); app.GeneralUITable.Data(~isRowEmpty, :)];
            else
                app.GeneralUITable.RowName = app.GeneralUITable.RowName(~isRowEmpty);
                app.GeneralUITable.Data = app.GeneralUITable.Data(~isRowEmpty, :);
            end
        end

        function updateConstantinoutsUITable(app)

            app.ConstantinputsUITable.Data = [];

            nonRangedInputIndex = app.inputNameArray ~= "ratios";
            nonRangedInputNameArray = app.inputNameArray(nonRangedInputIndex);
            nonRangedInputUINameArray = app.inputUINameArray(nonRangedInputIndex);

            nonRangedInputIndex = nonRangedInputNameArray ~= app.rangedPropertyName;
            nonRangedInputNameArray = nonRangedInputNameArray(nonRangedInputIndex);
            nonRangedInputUINameArray = nonRangedInputUINameArray(nonRangedInputIndex);

            nonRangedPropertyValueArray = string.empty;
            for PropertyName = nonRangedInputNameArray
                nonRangedPropertyValueArray(end + 1) = app.solverInputs.(PropertyName);
            end

            for i = 1 : size(app.PropellantsEditableTable.nameColumn, 2)
                 ratioNameArray(i) = app.PropellantsEditableTable.nameColumn(i) + " ratio";
            end
            ratioValueArray = string(app.PropellantsEditableTable.ratioColumn);
            if app.rangedPropertyName == "ratios"
                ratioNameArray(app.rangedIndex) = [];
                ratioValueArray(app.rangedIndex) = [];
            end

            app.ConstantinputsUITable.RowName = [ratioNameArray, nonRangedInputUINameArray];
            app.ConstantinputsUITable.Data = [ratioValueArray, nonRangedPropertyValueArray]';
        end

        function updateThermodynamicsUITable(app)
            if app.rangedPropertyName == "none"
                nIterations = 1;
            else
                nIterations = app.nIterations;
            end
            
            app.ThermodynamicsUITable.Data = [];
            app.ThermodynamicsUITable.ColumnName = 1 : nIterations;
            
            stationIndex = app.StationDropDown.ValueIndex;
            stationName = app.stationNameArray(stationIndex);
            stationUIName = app.stationUINameArray(stationIndex);
            for i = 1 : length(app.stationOutputNameArray)
                propertyName = app.stationOutputNameArray(i);
                propertyUIName = app.stationOutputUINameArray(i);
                app.ThermodynamicsUITable.RowName{i} = propertyUIName;

                for j = 1 : nIterations

                    if ~isempty(app.solverOutputs(j).(stationName))
                    data = app.solverOutputs(j).(stationName).(propertyName);
                    else
                    data = [];
                    end
                    if class(data) == "logical"
                        if data == true
                            data = 'Yes';
                        else
                            data = 'No';
                        end
                    else
                        data = num2str(data);
                    end
                    app.ThermodynamicsUITable.Data{i, j} = data;
                end
            end
        end

        function updateChemicalspeciesUITable(app)
            import Gas.*
            if app.rangedPropertyName == "none"
                nIterations = 1;
            else
                nIterations = app.nIterations;
            end

            app.ChemicalspeciesUITable.Data = [];
            app.ChemicalspeciesUITable.ColumnName = 1 : nIterations;

            property = app.speciesOutputNameArray(app.speciesOutputUINameArray == app.SpeciespropertyDropDown.Value);

            stationIndex = app.StationDropDown.ValueIndex;
            stationName = app.stationNameArray(stationIndex);

            speciesNames = app.gas.speciesNames;
            for j = 1 : nIterations
                if ~isempty(app.solverOutputs(j).(stationName))
                    setstate(app.gas, app.solverOutputs(j).(stationName));
                    dataColumn = app.gas.(property);
                    data(:,j) = dataColumn;
                else
                    data(:,j) = zeros(length(speciesNames),1);
                end
            end
            for i = 1 : length(speciesNames)
                isRowEmpty(i) = true;
                for j = 1 : nIterations
                    if data(i, j) ~= 0
                        isRowEmpty(i) = false;
                    end
                end
                app.ChemicalspeciesUITable.RowName = speciesNames(~isRowEmpty);
                app.ChemicalspeciesUITable.Data = data(~isRowEmpty, :);
            end
        end

        function updateRange(app)
            app.NumberofiterationsSpinner.Value = app.nIterations;
            propertyIndex = app.rangeablePropertyNameArray == app.rangedPropertyName;
            if class(app.rangeableElementArray(propertyIndex)) == "EditableTable"
                app.rangeableElementArray(propertyIndex).enablerange(app.rangedIndex);
            elseif class(app.rangeableElementArray(propertyIndex)) == "EditFieldWithUnit"
                app.rangeableElementArray(propertyIndex).enablerange;
            end
        end

        function importengine(app, filename)
            if nargin == 1
                [filename, filefolder] = uigetfile("*.engine", "Open file", pwd + "\SavedEngines");
                if filename == 0
                    return
                end
            else
                filefolder = "SavedEngines";
            end
            path = fullfile(filefolder, filename);
            if ~isfile(path)
                warningMessage = sprintf("%s", "File """ + path + """ not found.");
                RocketAnalysisWarning(warningMessage);
                return
            end
            file = fopen(path, "r");

            engineName = fgetl(file);
            app.engineName = string(engineName(7:end));
            app.EngineNameEditField.Value = app.engineName
            % app.problemType.current = str2double(fscanf(file, "Problem: %s\n"));
            app.ProblemtypeButtonGroup.SelectedObject = app.ProblemtypeButtonGroup.Buttons(str2double(fscanf(file, "Problem: %s\n")));
            app.ProblemtypeButtonGroupSelectionChanged;
            source = string(fscanf(file, "Source: %s\n"));
            if ismember(source, app.propellantSourceArray)
                app.propellantSource = source;
                app.PropellantsourceDropDown.Value = source;
            else
                warningMessage = sprintf("%s\n\n%s\n", "Propellant source file for loaded engine is not currently loaded.", ...
                    "Currently loaded sources: ");
                for propellantSource = app.propellantSourceArray
                    warningMessage = warningMessage + sprintf("%s\n",app.propellantSource);
                end
                warningMessage = warningMessage + sprintf("\n%s", filename + " source: " + source);
                RocketAnalysisWarning(warningMessage);
            end

            app.mass_mole = string(fscanf(file, "Fraction: %s\n"));
            app.FractiontypeDropDown.Value = app.mass_mole;

            app.nIterations = str2double(fscanf(file, "Iter: %s\n"));

            app.rangedPropertyName = string(fscanf(file, "Ranged: %s\n"));

            app.rangedIndex = str2double(fscanf(file, "Index: %s\n", [1,1]));

            app.searchedIndicator = string(fscanf(file, "Indicator: %s\n"));
            if app.searchedIndicator == "specificImpulse"
            app.SearchedindicatorDropDown.Value = "Specific impulse";
            else
            app.SearchedindicatorDropDown.Value = "Characteristic velocity";
            end

            for property = app.rangeablePropertyNameArray
                if property ~= "ratios"
                    data = split(fgetl(file));
                    app.rangeablePropertyValueArray.(property) = str2double(data(2:3));
                end
            end

            app.PropellantsEditableTable.clean;
            nProps = str2double(fscanf(file, "Props: %s\n"));
            for i = 1 : nProps
                data = split(fgetl(file));
                propellantName = data(1) + ".prop";
                ratio(1:2) = str2double(data(2:3));
                app.importpropellant(propellantName, ratio);
            end

            app.updateRange;
            app.setfields;
        end

        function exportengine(app, filename)
            a = app.nIterations;
            b = app.rangedPropertyName;
            c = app.rangedIndex;
            app.applyfields;
            if nargin == 1
                    [filename, filefolder] = uiputfile("*.engine", "Save as", pwd + "\SavedEngines\" + app.engineName);
                if filename == 0
                    return
                end
            else
                filefolder = "SavedEngines";
            end
            file = fopen(fullfile(filefolder, filename), "w");
            fprintf(file, "Name:\t" + app.engineName + "\n");
            fprintf(file, "Problem:\t" + app.problemType.current + "\n");
            fprintf(file, "Source:\t" + app.propellantSource + "\n");
            fprintf(file, "Fraction:\t" + app.mass_mole + "\n");
            fprintf(file, "Iter:\t" + app.nIterations + "\n");
            fprintf(file, "Ranged:\t" + app.rangedPropertyName + "\n");
            fprintf(file, "Index:\t" + app.rangedIndex + "\n");
            fprintf(file, "Indicator:\t" + app.searchedIndicator + "\n");
            for property = app.rangeablePropertyNameArray
                if property ~= "ratios"
                    fprintf(file, property + ":\t" + app.rangeablePropertyValueArray.(property)(1) + "\t" + app.rangeablePropertyValueArray.(property)(2) + "\n");
                end
            end

            nProps = size(app.propellantArray, 2);
            fprintf(file, "Props:\t" + nProps + "\n");
            for i = 1 : nProps
                propellantName = app.PropellantsEditableTable.nameColumn(i);
                propellantRatio = app.PropellantsEditableTable.ratioColumn(i);
                propellantRatio2 = app.PropellantsEditableTable.ratio2Column(i);
                a = app.propellantArray;
                fprintf(file, propellantName + "\t" + propellantRatio + "\t" + propellantRatio2 + "\n");
            end
            fclose(file);
        end

        function importpropellant(app, filename, ratio)
            if nargin == 1
                [filename, filefolder] = uigetfile("*.prop", "Open file", pwd + "\SavedPropellants");
                if filename == 0
                    return
                end
            else
                filefolder = "SavedPropellants";
            end
            path = fullfile(filefolder, filename);
            if ~isfile(path)
                warningMessage = sprintf("%s", "File """ + path + """ not found.");
                RocketAnalysisWarning(warningMessage);
                return
            end
            file = fopen(path, "r");
            propellantName = fgetl(file);
            propellantName = string(propellantName(7:end));
            source = string(fscanf(file, "Source:\t%s\n"));

            if source ~= app.propellantSource
                warningMessage = sprintf("%s\n\n%s\n\n%s", "Source file for loaded propellant does not match currently selected source.", ...
                    "Current source: " + app.propellantSource, filename + " source: " + source);
                RocketAnalysisWarning(warningMessage);
                return
            end

            nSpecies = double(string(fscanf(file, "%f species:\n")));
            nameColumn = string.empty;
            ratioColumn = [];
            for i = 1 : nSpecies
                data = split(fgetl(file));
                nameColumn(i) = string(data{1});
                ratioColumn(i) = str2double(data{2});
            end

            fclose(file);

            propellantData.nameColumn = nameColumn;
            propellantData.ratioColumn = ratioColumn;
            propellantData.nSpecies = nSpecies;

            if nargin ~= 3
                ratio = [1,1];
            end
            app.PropellantsEditableTable.addrow(propellantName, ratio, "%", propellantData);
        end

        function exportpropellant(app, filename)
            selection = app.PropellantsEditableTable.selection;
            if ~isempty(selection)
                app.PropellantsEditableTable.updateselection;
                propellantName = app.PropellantsEditableTable.nameColumn(selection);
                if nargin == 1
                    [filename, filefolder] = uiputfile("*.prop", "Save as", pwd + "\SavedPropellants\" + propellantName);
                    if filename == 0
                        return
                    end
                else
                    filefolder = "SavedPropellants";
                end
                file = fopen(fullfile(filefolder, filename), "w");
                data = app.PropellantsEditableTable.getdata(selection);
                data = [string(data.nameColumn); data.ratioColumn];

                fprintf(file, "Name:\t" + propellantName + "\n");
                fprintf(file, "Source:\t" + app.gas.src + "\n");
                fprintf(file, size(data, 2) + " species:\n");

                fprintf(file, "%s\t%s\n", data);
                fclose(file);
            else
                warningMessage = "Select a propellant before saving.";
                RocketAnalysisWarning(warningMessage);
            end
        end

        function updateunits(app)
            import Unit.*

            for editField = app.rangeableElementArray
                editField.updateunits(app.unitArray);
            end
        end

        function setinputs(app, iteration)
            import Gas.*
            
            if iteration == 1
                app.solverInputs.gas = app.gas;
                app.solverInputs.atmo = app.atmo;
                app.solverInputs.propellantArray = app.propellantArray;
                app.solverInputs.mass_mole = app.mass_mole;
                app.solverInputs.searchedIndicator = app.searchedIndicator;
            end

            for propertyName = app.rangeablePropertyNameArray
                app.solverInputs.(propertyName) = app.rangeablePropertyValueArray.(propertyName)(:, 1);
            end
            if app.rangedPropertyName ~= "none"
                app.solverInputs.(app.rangedPropertyName) = app.rangeablePropertyValueArray.(app.rangedPropertyName)(:, 1) / (app.nIterations - 1) * (app.nIterations - iteration) + ...
                    app.rangeablePropertyValueArray.(app.rangedPropertyName)(:, 2) / (app.nIterations - 1) * (iteration - 1);
            end
            app.solverInputs.ratios = app.solverInputs.ratios';
            setstate(app.solverInputs.atmo, 'T', 288.15, ...
                'P', app.solverInputs.pressure_atmo, 'velocity', 0);
        end

        function EditFieldRangeSwitched(app, event)
            app.NumberofiterationsSpinner.Enable = "on";
            app.NumberofiterationsSpinnerLabel.Enable = "on";
            for rangeableElement = app.rangeableElementArray
                rangeableElement.disablerange;
                if rangeableElement.isRanged == true
                    app.rangedPropertyName = rangeableElement.propertyName;
                    if class(rangeableElement) == "EditableTable"
                        app.rangedIndex = rangeableElement.rangedIndex;
                    else
                        app.rangedIndex = 0;
                    end
                end
            end
        end

        function EditFieldRangeDisabled(app, event)
            app.NumberofiterationsSpinner.Enable = "off";
            app.NumberofiterationsSpinnerLabel.Enable = "off";
            app.rangedPropertyName = "none";
            app.rangedIndex = 0;
        end

        function importunits(app)
            import Unit.*

            folders = strtrim(string(ls('Units')));
            folders = ("Units\" + folders(3 : end))';
            for folder = folders
                files = strtrim(string(ls(folder)));
                files = (folder + "\" + files(3 : end))';
                i = 1;
                for file = files
                    unit = Unit(file);
                    unitTypes().tostr(unit.gettype);
                    app.unitArray.(unitTypes().tostr(unit.gettype))(i) = unit;
                    i = i + 1;
                end
            end
            updateunits(app);
        end

        function setuprangeableelementarray(app)
            app.rangeableElementArray = EditFieldWithUnit.empty;
            app.rangeablePropertyNameArray = string.empty;
            app.rangeableElementArray(end + 1) = app.InjectionPressureEditFieldWithUnit;
            app.rangeableElementArray(end).reSetupFcn;

            app.rangeableElementArray(end + 1) = app.InjectionTemperatureEditFieldWithUnit;
            app.rangeableElementArray(end).reSetupFcn;

            app.rangeableElementArray(end + 1) = app.ContractionRatioEditFieldWithUnit;
            app.rangeableElementArray(end).reSetupFcn;

            app.rangeableElementArray(end + 1) = app.MaxChamberTemperatureEditFieldWithUnit;
            app.rangeableElementArray(end).reSetupFcn;

            app.rangeableElementArray(end + 1) = app.MaxThroatTemperatureEditFieldWithUnit;
            app.rangeableElementArray(end).reSetupFcn;

            app.rangeableElementArray(end + 1) = app.HeatPowerEditFieldWithUnit;
            app.rangeableElementArray(end).reSetupFcn;

            app.rangeableElementArray(end + 1) = app.ExpansionRatioEditFieldWithUnit;
            app.rangeableElementArray(end).reSetupFcn;

            app.rangeableElementArray(end + 1) = app.SeparationOffsetEditFieldWithUnit;
            app.rangeableElementArray(end).reSetupFcn;

            app.rangeableElementArray(end + 1) = app.AtmosphericPressureEditFieldWithUnit;
            app.rangeableElementArray(end).reSetupFcn;

            app.rangeableElementArray(end + 1) = app.ThrustEditFieldWithUnit;
            app.rangeableElementArray(end).reSetupFcn;

            app.rangeableElementArray(end + 1) = app.PropellantsEditableTable;

            for editField = app.rangeableElementArray
                %editField.updateunits(app.unitArray);
                editField.rangeswitchedFcn = createCallbackFcn(app, @EditFieldRangeSwitched, true);
                editField.rangedisabledFcn = createCallbackFcn(app, @EditFieldRangeDisabled, true);
                app.rangeablePropertyNameArray(end + 1) = editField.propertyName;
                app.rangeablePropertyValueArray.(editField.propertyName) = [];
            end

        end

        function setuptables(app)
            app.SpeciesEditableTable.updateunits(app.unitArray);
            app.SpeciesEditableTable.resetup;
            app.SpeciesEditableTable.disable;
            app.PropellantsEditableTable.updateunits(app.unitArray);
            app.PropellantsEditableTable.nameWhitelist = "propellant";
            app.PropellantsEditableTable.resetup;
        end

        function sources = updatepropellantSourceArray(app)
            sources = strtrim(string(ls('Phases')));
            sources = sources(3 : end)';
            app.propellantSourceArray = sources;
            app.PropellantsourceDropDown.Items = sources;
            app.PropellantsourceDropDown.ValueIndex = 1;
            app.PropellantsourceDropDownValueChanged;
        end

        function state = statefromdata(app, speciesNames, massFractions)
            import Gas.*
            state.massFractions = zeros(size(app.gas.massFractions, 1), 1);
            nameList = app.gas.speciesNames;
            for i = 1 : size(app.gas.massFractions, 1)
                massFraction = massFractions(find(speciesNames == nameList(i)));
                massFractionSum = sum(massFraction);
                state.massFractions(i) = massFractionSum;
            end
            setstate(app.gas, 'Y', state.massFractions, 'T', app.rangeablePropertyValueArray.temperature_injection(1), 'P', app.rangeablePropertyValueArray.pressure_injection(1));
            state = State(app.gas);
        end

        function applyfields(app)
            app.canRun = true;
            app.engineName = app.EngineNameEditField.Value;
            app.nIterations = app.NumberofiterationsSpinner.Value;
            app.PropellantsEditableTable.updateselection;

            for rangeableElement = app.rangeableElementArray
                property = rangeableElement.propertyName;
                if property ~= "ratios"
                    app.rangeablePropertyValueArray.(property) = rangeableElement.value;
                else
                    app.rangeablePropertyValueArray.ratios = [];
                    if app.problemType.current == 3 && length(rangeableElement.ratioColumn) < 2
                        warningMessage = sprintf("%s\n%s", "Not enough defined propellants.", ...
                            "At least two propellants need to be defined for optimal mixture problem type");
                        RocketAnalysisWarning(warningMessage);
                        app.canRun = false;
                    elseif isempty(rangeableElement.ratioColumn)
                        warningMessage = sprintf("%s\n%s", "There are no defined propellants.", "At least one propellant need to be defined");
                        RocketAnalysisWarning(warningMessage);
                        app.canRun = false;
                    elseif ~isempty(rangeableElement.ratioColumn)
                        app.rangeablePropertyValueArray.ratios(:, 1) = rangeableElement.ratioColumn;
                        app.rangeablePropertyValueArray.ratios(:, 2) = rangeableElement.ratio2Column;
                        app.propellantArray = State.empty;
                        for i = 1 : size(rangeableElement.dataColumn, 2)
                            data = rangeableElement.dataColumn{i};
                            if isempty(data) || isempty(data.nameColumn)
                                warningMessage = sprintf("%s\n%s", "Propellant """ + rangeableElement.nameColumn(i) + """ has no species assigned.", ...
                                    "All propellants need to be defined.");
                                RocketAnalysisWarning(warningMessage);
                                app.canRun = false;
                                return
                            end
                            speciesNames = data.nameColumn;
                            massFractions = data.ratioColumn;
                            app.propellantArray(end + 1) = app.statefromdata(speciesNames, massFractions);
                        end
                    end
                end
            end
        end

        function setfields(app)
            for  rangeableElement = app.rangeableElementArray
                property = rangeableElement.propertyName;
                if property ~= "ratios"
                rangeableElement.value = app.rangeablePropertyValueArray.(property);
                end
            end
        end
        
        function applyspeciestable(app)
            selection = app.PropellantsEditableTable.selection;
            selection_old = app.PropellantsEditableTable.selection_old;

            if ~isempty(selection_old)
                app.PropellantsEditableTable.setdata(selection_old, app.SpeciesEditableTable.save);
            end
            if ~isempty(selection)
                app.SpeciesEditableTable.clean;
                if ~(isempty(app.PropellantsEditableTable.getdata(selection)) || isempty(app.PropellantsEditableTable.getdata(selection).nameColumn))
                    app.SpeciesEditableTable.load(app.PropellantsEditableTable.getdata(selection));
                end
                app.SpeciesEditableTable.enable;
            else
                app.SpeciesEditableTable.disable;
                app.SpeciesEditableTable.clean;
            end
        end
        
        function setenginename(app, newName)
            app.engineName = newName;
            app.EngineNameEditField.value = newName;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            % addpathall(pwd, ".git", true);
            app.importunits;
            app.setuprangeableelementarray;
            app.updateunits;
            app.updatepropellantSourceArray;
            app.setuptables;
            app.problemType.current = 1;
            app.mass_mole = "mass";
            app.searchedIndicator = "specificImpulse";
            app.atmo = Gas("air");

            for i = 1 : 3
                app.ProblemtypeButtonGroup.Buttons(i).UserData = i;
            end

            app.StationDropDown.Items = app.stationUINameArray;
            app.importengine("RS-25.engine");
        end

        % Button pushed function: RunanalysisButton
        function run(app, event)
            app.applyfields;
            global solverError
            solverError = "ERROR: Unknown error";
            if ~app.canRun
                return
            end

            if app.rangedPropertyName == "none"
                nIterations = 1;
            else
                nIterations = app.nIterations;
            end
            app.rangedInputValue = [];
            for i = 1 : nIterations
                app.setinputs(i);
                solverInputs = app.solverInputs;
                try
                if app.problemType.current == 1
                    solverOutputs(i) = engineanalysis(solverInputs);
                elseif app.problemType.current == 2
                    solverOutputs(i) = optimalnozzle(solverInputs);
                elseif app.problemType.current == 3
                    if length(app.propellantArray) < 2
                        warningMessage = sprintf("%s", "You need at least two propellants for optimal mixture problem type.");
                        RocketAnalysisWarning(warningMessage);
                        return
                    end
                    solverOutputs(i) = optimalmixture(solverInputs);
                end
                catch
                    solverOutputs(i) = solvererroroutput;
                end
                if app.rangedPropertyName ~= "none"
                    app.rangedInputValue(:, i) = solverInputs.(app.rangedPropertyName);
                end
            end
            app.solverOutputs = [];
            app.solverOutputs = solverOutputs;
            app.updateGeneralUITable;
            app.updateConstantinoutsUITable;
            app.updateThermodynamicsUITable;
            app.updateChemicalspeciesUITable;
            app.MainTabGroup.SelectedTab = app.OutputsTab;
        end

        % Value changed function: PropellantsourceDropDown
        function PropellantsourceDropDownValueChanged(app, event)
            app.propellantSource = app.propellantSourceArray(app.PropellantsourceDropDown.ValueIndex);
            app.gas = Gas(app.propellantSource);
            app.SpeciesEditableTable.nameWhitelist = app.gas.speciesNames;
            app.SpeciesEditableTable.clean;
            % app.PropellantsEditableTable.clean;
            app.propellantArray = [];
        end

        % Callback function: PropellantsEditableTable
        function PropellantsEditableTableselectionchanged(app, event)
            app.applyspeciestable;
        end

        % Menu selected function: ExportPropellantMenu
        function ExportPropellantMenuSelected(app, event)
            app.exportpropellant;
        end

        % Menu selected function: ImportPropellantMenu
        function ImportPropellantMenuSelected(app, event)
            app.importpropellant;
        end

        % Menu selected function: ExportEngineMenu
        function ExportEngineMenuSelected(app, event)
            app.exportengine;
        end

        % Menu selected function: ImportEngineMenu
        function ImportEngineMenuSelected(app, event)
            app.importengine;
        end

        % Selection changed function: ProblemtypeButtonGroup
        function ProblemtypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ProblemtypeButtonGroup.SelectedObject.UserData;
            app.problemType.current = selectedButton;
            if selectedButton == 1
                inputNameArray = app.engineAnalysisAllowedInputNameArray;
                app.SearchedindicatorDropDown.Enable = false;
                app.SearchedindicatorDropDownLabel.Enable = false;
            elseif selectedButton == 2
                inputNameArray = app.optimalNozzleAllowedInputNameArray;
                app.SearchedindicatorDropDown.Enable = false;
                app.SearchedindicatorDropDownLabel.Enable = false;
            else
                inputNameArray = app.optimalMixtureAllowedInputNameArray;
                app.SearchedindicatorDropDown.Enable = true;
                app.SearchedindicatorDropDownLabel.Enable = true;
            end
            for editField = app.rangeableElementArray
                if ismember(editField.propertyName, inputNameArray)
                    editField.enable;
                else
                    editField.disable;
                end
            end
        end

        % Value changed function: StationDropDown
        function StationDropDownValueChanged(app, event)
            app.updateThermodynamicsUITable
            app.updateChemicalspeciesUITable
        end

        % Value changed function: SpeciespropertyDropDown
        function SpeciespropertyDropDownValueChanged(app, event)
            app.updateChemicalspeciesUITable
        end

        % Value changed function: EngineNameEditField
        function EngineNameEditFieldValueChanged(app, event)
            app.engineName = app.EngineNameEditField.Value;
        end

        % Callback function
        function DEBUGButtonPushed(app, event)
            a = app.rangedPropertyName
        end

        % Value changed function: SearchedindicatorDropDown
        function SearchedindicatorDropDownValueChanged(app, event)
            valueIndex = app.SearchedindicatorDropDown.ValueIndex;
            if valueIndex == 1
                app.searchedIndicator = "specificImpulse";
            else
                app.searchedIndicator = "characteristicVelocity";
            end
        end

        % Value changed function: FractiontypeDropDown
        function FractiontypeDropDownValueChanged(app, event)
            app.mass_mole = app.FractiontypeDropDown.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RocketAppUIFigure = uifigure('Visible', 'off');
            app.RocketAppUIFigure.Name = 'RocketApp';
            app.FileMenu = uimenu(app.RocketAppUIFigure);
            app.FileMenu.Text = 'File';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Text = 'Save';
            app.ExportPropellantMenu = uimenu(app.SaveMenu);
            app.ExportPropellantMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportPropellantMenuSelected, true);
            app.ExportPropellantMenu.Text = 'Propellant';
            app.ExportEngineMenu = uimenu(app.SaveMenu);
            app.ExportEngineMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportEngineMenuSelected, true);
            app.ExportEngineMenu.Text = 'Engine';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.Text = 'Open';
            app.ImportPropellantMenu = uimenu(app.OpenMenu);
            app.ImportPropellantMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportPropellantMenuSelected, true);
            app.ImportPropellantMenu.Text = 'Propellant';
            app.ImportEngineMenu = uimenu(app.OpenMenu);
            app.ImportEngineMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportEngineMenuSelected, true);
            app.ImportEngineMenu.Text = 'Engine';
            app.MainLayout = uigridlayout(app.RocketAppUIFigure);
            app.MainTabGroup = uitabgroup(app.MainLayout);
            app.InputsTab = uitab(app.MainTabGroup);
            app.InputsTab.Title = 'Inputs';
            app.InputsGridLayout = uigridlayout(app.InputsTab);
            app.PropellantSpeciesPanel = uipanel(app.InputsGridLayout);
            app.PropellantSpeciesPanel.Title = 'Selected propellant species';
            app.PropellantSpeciesGridLayout = uigridlayout(app.PropellantSpeciesPanel);
            app.SpeciesEditableTable = EditableTable(app.PropellantSpeciesGridLayout);
            app.SpeciesEditableTable.canBeRanged = false;
            app.PropellantsPanel = uipanel(app.InputsGridLayout);
            app.PropellantsPanel.Title = 'Propellants';
            app.PropellantsGridLayout = uigridlayout(app.PropellantsPanel);
            app.PropellantsEditableTable = EditableTable(app.PropellantsGridLayout);
            app.PropellantsEditableTable.hasNameWhitelist = false;
            app.PropellantsEditableTable.propertyName = "ratios";
            app.PropellantsEditableTable.selectionchangedFcn = createCallbackFcn(app, @PropellantsEditableTableselectionchanged, true);
            app.EnginedefinitionPanel = uipanel(app.InputsGridLayout);
            app.EnginedefinitionPanel.Title = 'Engine definition';
            app.EngineDefinitionGridLayout = uigridlayout(app.EnginedefinitionPanel);
            app.AtmosphericPressureEditFieldWithUnit = EditFieldWithUnit(app.EngineDefinitionGridLayout);
            app.AtmosphericPressureEditFieldWithUnit.limits = [0 Inf];
            app.AtmosphericPressureEditFieldWithUnit.name = "Atmospheric pressure";
            app.AtmosphericPressureEditFieldWithUnit.propertyName = "pressure_atmo";
            app.AtmosphericPressureEditFieldWithUnit.unitType = 5;
            app.AtmosphericPressureEditFieldWithUnit.value = [101325 0];
            app.AtmosphereLabel = uilabel(app.EngineDefinitionGridLayout);
            app.AtmosphereLabel.Text = 'Atmosphere';
            app.SeparationOffsetEditFieldWithUnit = EditFieldWithUnit(app.EngineDefinitionGridLayout);
            app.SeparationOffsetEditFieldWithUnit.defaultUnitIndex = 2;
            app.SeparationOffsetEditFieldWithUnit.limits = [-1 1];
            app.SeparationOffsetEditFieldWithUnit.name = "Separation tolerance";
            app.SeparationOffsetEditFieldWithUnit.propertyName = "separationTolerance";
            app.SeparationOffsetEditFieldWithUnit.unitType = 9;
            app.SeparationOffsetEditFieldWithUnit.value = [0 0.1];
            app.ExpansionRatioEditFieldWithUnit = EditFieldWithUnit(app.EngineDefinitionGridLayout);
            app.ExpansionRatioEditFieldWithUnit.defaultUnitIndex = 4;
            app.ExpansionRatioEditFieldWithUnit.limits = [1 10000];
            app.ExpansionRatioEditFieldWithUnit.name = "Expansion ratio";
            app.ExpansionRatioEditFieldWithUnit.propertyName = "expansionRatio";
            app.ExpansionRatioEditFieldWithUnit.unitType = 9;
            app.ExpansionRatioEditFieldWithUnit.value = [20 40];
            app.NozzleLabel = uilabel(app.EngineDefinitionGridLayout);
            app.NozzleLabel.Text = 'Nozzle';
            app.HeatPowerEditFieldWithUnit = EditFieldWithUnit(app.EngineDefinitionGridLayout);
            app.HeatPowerEditFieldWithUnit.defaultUnitIndex = [4 2];
            app.HeatPowerEditFieldWithUnit.dimension = [1 -1];
            app.HeatPowerEditFieldWithUnit.limits = [0 Inf];
            app.HeatPowerEditFieldWithUnit.name = "Heat supplied";
            app.HeatPowerEditFieldWithUnit.propertyName = "heatMass";
            app.HeatPowerEditFieldWithUnit.unitType = [7 2];
            app.HeatPowerEditFieldWithUnit.value = [0 1000];
            app.MaxThroatTemperatureEditFieldWithUnit = EditFieldWithUnit(app.EngineDefinitionGridLayout);
            app.MaxThroatTemperatureEditFieldWithUnit.defaultUnitIndex = 3;
            app.MaxThroatTemperatureEditFieldWithUnit.limits = [273.15 Inf];
            app.MaxThroatTemperatureEditFieldWithUnit.name = "Max throat temperature";
            app.MaxThroatTemperatureEditFieldWithUnit.propertyName = "temperature_throat_max";
            app.MaxThroatTemperatureEditFieldWithUnit.unitType = 6;
            app.MaxThroatTemperatureEditFieldWithUnit.value = [2000 3000];
            app.MaxChamberTemperatureEditFieldWithUnit = EditFieldWithUnit(app.EngineDefinitionGridLayout);
            app.MaxChamberTemperatureEditFieldWithUnit.defaultUnitIndex = 3;
            app.MaxChamberTemperatureEditFieldWithUnit.limits = [273.15 Inf];
            app.MaxChamberTemperatureEditFieldWithUnit.name = "Max chamber temperature";
            app.MaxChamberTemperatureEditFieldWithUnit.propertyName = "temperature_chamber_max";
            app.MaxChamberTemperatureEditFieldWithUnit.unitType = 6;
            app.MaxChamberTemperatureEditFieldWithUnit.value = [2000 3000];
            app.ContractionRatioEditFieldWithUnit = EditFieldWithUnit(app.EngineDefinitionGridLayout);
            app.ContractionRatioEditFieldWithUnit.defaultUnitIndex = 4;
            app.ContractionRatioEditFieldWithUnit.limits = [1 100];
            app.ContractionRatioEditFieldWithUnit.name = "Contraction ratio";
            app.ContractionRatioEditFieldWithUnit.propertyName = "contractionRatio";
            app.ContractionRatioEditFieldWithUnit.unitType = 9;
            app.ContractionRatioEditFieldWithUnit.value = [10 15];
            app.ChamberLabel = uilabel(app.EngineDefinitionGridLayout);
            app.ChamberLabel.Text = 'Chamber';
            app.ThrustEditFieldWithUnit = EditFieldWithUnit(app.EngineDefinitionGridLayout);
            app.ThrustEditFieldWithUnit.limits = [0 Inf];
            app.ThrustEditFieldWithUnit.name = "Thrust";
            app.ThrustEditFieldWithUnit.propertyName = "thrust";
            app.ThrustEditFieldWithUnit.unitType = 4;
            app.ThrustEditFieldWithUnit.value = [500 1000];
            app.InjectionTemperatureEditFieldWithUnit = EditFieldWithUnit(app.EngineDefinitionGridLayout);
            app.InjectionTemperatureEditFieldWithUnit.defaultUnitIndex = 3;
            app.InjectionTemperatureEditFieldWithUnit.limits = [1 5000];
            app.InjectionTemperatureEditFieldWithUnit.name = "Injection temperature";
            app.InjectionTemperatureEditFieldWithUnit.propertyName = "temperature_injection";
            app.InjectionTemperatureEditFieldWithUnit.unitType = 6;
            app.InjectionTemperatureEditFieldWithUnit.value = [100 150];
            app.InjectionPressureEditFieldWithUnit = EditFieldWithUnit(app.EngineDefinitionGridLayout);
            app.InjectionPressureEditFieldWithUnit.defaultUnitIndex = 2;
            app.InjectionPressureEditFieldWithUnit.limits = [1 Inf];
            app.InjectionPressureEditFieldWithUnit.name = "Injection pressure";
            app.InjectionPressureEditFieldWithUnit.propertyName = "pressure_injection";
            app.InjectionPressureEditFieldWithUnit.unitType = 5;
            app.InjectionPressureEditFieldWithUnit.value = [1000000 2000000];
            app.InjectionLabel = uilabel(app.EngineDefinitionGridLayout);
            app.InjectionLabel.Text = 'Injection';
            app.RunanalysisButton = uibutton(app.InputsGridLayout, 'push');
            app.RunanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @run, true);
            app.RunanalysisButton.Text = 'Run analysis';
            app.ProblemDefinitionPanel = uipanel(app.InputsGridLayout);
            app.ProblemDefinitionPanel.Title = 'Problem definition';
            app.ProblemDefinitionGridLayout = uigridlayout(app.ProblemDefinitionPanel);
            app.ProblemsettingsLabel = uilabel(app.ProblemDefinitionGridLayout);
            app.ProblemsettingsLabel.Text = 'Problem settings';
            app.PropellantsourceDropDownLabel = uilabel(app.ProblemDefinitionGridLayout);
            app.PropellantsourceDropDownLabel.Text = 'Propellant source';
            app.PropellantsourceDropDown = uidropdown(app.ProblemDefinitionGridLayout);
            app.PropellantsourceDropDown.Items = {'R1highT', 'gri30', 'air'};
            app.PropellantsourceDropDown.ValueChangedFcn = createCallbackFcn(app, @PropellantsourceDropDownValueChanged, true);
            app.PropellantsourceDropDown.Value = 'R1highT';
            app.FractiontypeDropDownLabel = uilabel(app.ProblemDefinitionGridLayout);
            app.FractiontypeDropDownLabel.Text = 'Fraction type';
            app.FractiontypeDropDown = uidropdown(app.ProblemDefinitionGridLayout);
            app.FractiontypeDropDown.Items = {'mass', 'mole'};
            app.FractiontypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FractiontypeDropDownValueChanged, true);
            app.FractiontypeDropDown.Value = 'mass';
            app.NumberofiterationsSpinnerLabel = uilabel(app.ProblemDefinitionGridLayout);
            app.NumberofiterationsSpinnerLabel.Enable = 'off';
            app.NumberofiterationsSpinnerLabel.Text = 'Number of iterations';
            app.NumberofiterationsSpinner = uispinner(app.ProblemDefinitionGridLayout);
            app.NumberofiterationsSpinner.Limits = [2 100];
            app.NumberofiterationsSpinner.RoundFractionalValues = 'on';
            app.NumberofiterationsSpinner.Enable = 'off';
            app.NumberofiterationsSpinner.Value = 2;
            app.ProblemtypeButtonGroup = uibuttongroup(app.ProblemDefinitionGridLayout);
            app.ProblemtypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ProblemtypeButtonGroupSelectionChanged, true);
            app.ProblemtypeButtonGroup.Title = 'Problem type';
            app.EngineAnalysisButton = uiradiobutton(app.ProblemtypeButtonGroup);
            app.EngineAnalysisButton.Text = 'Engine Analysis';
            app.EngineAnalysisButton.Value = true;
            app.FindOptimalNozzleButton = uiradiobutton(app.ProblemtypeButtonGroup);
            app.FindOptimalNozzleButton.Text = 'Find Optimal Nozzle';
            app.FindOptimalMixtureButton = uiradiobutton(app.ProblemtypeButtonGroup);
            app.FindOptimalMixtureButton.Text = 'Find Optimal Mixture';
            app.SearchedindicatorDropDownLabel = uilabel(app.ProblemDefinitionGridLayout);
            app.SearchedindicatorDropDownLabel.Text = 'Searched indicator';
            app.SearchedindicatorDropDown = uidropdown(app.ProblemDefinitionGridLayout);
            app.SearchedindicatorDropDown.Items = {'Specific impulse', 'Characterstic velocity'};
            app.SearchedindicatorDropDown.ValueChangedFcn = createCallbackFcn(app, @SearchedindicatorDropDownValueChanged, true);
            app.SearchedindicatorDropDown.Value = 'Specific impulse';
            app.EngineNameLabel = uilabel(app.InputsGridLayout);
            app.EngineNameLabel.Text = 'Engine Name:';
            app.EngineNameEditField = uieditfield(app.InputsGridLayout, 'text');
            app.EngineNameEditField.ValueChangedFcn = createCallbackFcn(app, @EngineNameEditFieldValueChanged, true);
            app.EngineNameEditField.Value = 'RS-25';
            app.OutputsTab = uitab(app.MainTabGroup);
            app.OutputsTab.Title = 'Outputs';
            app.OutputsGridLayout = uigridlayout(app.OutputsTab);
            app.OutputsTabGroup = uitabgroup(app.OutputsGridLayout);
            app.SummaryTab = uitab(app.OutputsTabGroup);
            app.SummaryTab.Title = 'Summary';
            app.SummaryGridLayout = uigridlayout(app.SummaryTab);
            app.GeneralPanel = uipanel(app.SummaryGridLayout);
            app.GeneralPanel.Title = 'General';
            app.GeneralGridLayout = uigridlayout(app.GeneralPanel);
            app.GeneralUITable = uitable(app.GeneralGridLayout);
            app.GeneralUITable.ColumnName = '';
            app.GeneralUITable.RowName = {};
            app.ConstantinputsPanel = uipanel(app.SummaryGridLayout);
            app.ConstantinputsPanel.Title = 'Constant inputs';
            app.ConstantinputsLayout = uigridlayout(app.ConstantinputsPanel);
            app.ConstantinputsUITable = uitable(app.ConstantinputsLayout);
            app.ConstantinputsUITable.ColumnName = '';
            app.ConstantinputsUITable.RowName = {};
            app.StationsTab = uitab(app.OutputsTabGroup);
            app.StationsTab.Title = 'Stations';
            app.StationsGridLayout = uigridlayout(app.StationsTab);
            app.ThermodynamicsPanel = uipanel(app.StationsGridLayout);
            app.ThermodynamicsPanel.Title = 'Thermodynamics';
            app.ThermodynamicsGridLayout = uigridlayout(app.ThermodynamicsPanel);
            app.ThermodynamicsUITable = uitable(app.ThermodynamicsGridLayout);
            app.ThermodynamicsUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ThermodynamicsUITable.RowName = {};
            app.ChemicalspeciesPanel = uipanel(app.StationsGridLayout);
            app.ChemicalspeciesPanel.Title = 'Chemical species';
            app.ChemicalspeciesGridLayout = uigridlayout(app.ChemicalspeciesPanel);
            app.ChemicalspeciesUITable = uitable(app.ChemicalspeciesGridLayout);
            app.ChemicalspeciesUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ChemicalspeciesUITable.RowName = {};
            app.SpeciespropertyDropDownLabel = uilabel(app.ChemicalspeciesGridLayout);
            app.SpeciespropertyDropDownLabel.Text = 'Species property';
            app.SpeciespropertyDropDown = uidropdown(app.ChemicalspeciesGridLayout);
            app.SpeciespropertyDropDown.Items = {'Mass fractions', 'Mole fractions', 'Molecular weights', 'Chemical potentials'};
            app.SpeciespropertyDropDown.ValueChangedFcn = createCallbackFcn(app, @SpeciespropertyDropDownValueChanged, true);
            app.SpeciespropertyDropDown.Value = 'Mass fractions';
            app.StationDropDownLabel = uilabel(app.StationsGridLayout);
            app.StationDropDownLabel.Text = 'Station';
            app.StationDropDown = uidropdown(app.StationsGridLayout);
            app.StationDropDown.Items = {'Injection', 'Chamber', 'Throat', 'Exit', 'Before the shock', 'After the shock'};
            app.StationDropDown.ValueChangedFcn = createCallbackFcn(app, @StationDropDownValueChanged, true);
            app.StationDropDown.Value = 'Exit';
            % Show the figure after all components are created
            app.RocketAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RocketApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RocketAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RocketAppUIFigure)
        end
    end
end