classdef DisplayFieldWithUnit < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout      matlab.ui.container.GridLayout
        EditFieldLabel  matlab.ui.control.Label
        EditField       matlab.ui.control.NumericEditField
        UnitDropDown    matlab.ui.control.DropDown
    end

    properties (Access = private)
        unit Unit = Unit('Units\Length\meter.unit');
        unitArray
    end
    
    properties (Access = public)
        defaultUnitIndex double {mustBeInteger(defaultUnitIndex)} = 1;
        dimension double {mustBeInteger(dimension)} = 1;
        name string = "Edit Field";
        subUnit Unit
        unitType double {mustBeInteger(unitType)} = 1;
        value (1,1) double = 1;
    end
    
    methods (Access = public)
        function updateunits(comp, unitArray, index)
            arguments
                comp;
                unitArray;
                index = comp.defaultUnitIndex;
            end
            comp.unitArray = unitArray;
            for i = 1 : size(comp.unitType, 2)
                comp.UnitDropDown(i).Items = cell.empty;
                type = Unit.unitTypes.tostr(comp.unitType(i));
                for unit = unitArray.(type)
                    comp.UnitDropDown(i).Items{end + 1} = convertStringsToChars(unit.getlatex(comp.dimension(i)));
                end
                comp.UnitDropDown(i).ValueIndex = index(i);
                comp.subUnit(i) = unitArray.(type)(index(i));
            end
            comp.unit = Unit(comp.subUnit, comp.dimension);
        end
%{
        function importunits(app)
            import Unit.*

            folders = strtrim(string(ls('Units')));
            folders = ("Units\" + folders(3 : end))';
            for folder = folders
                files = strtrim(string(ls(folder)));
                files = (folder + "\" + files(3 : end))';
                i = 1;
                for file = files
                    unit = Unit(file);
                    unitArray.(unitTypes().tostr(unit.gettype))(i) = unit;
                    i = i + 1;
                end
            end
            updateunits(app, unitArray;
        end
%}
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            comp.GridLayout.ColumnWidth = [{'3x', '2x'}, num2cell(ones(1, size(comp.unitType, 2)) * 60)];
            style = uistyle("Interpreter", "latex");
            comp.unitType
            for i = 1 : size(comp.unitType, 2)
                comp.UnitDropDown(i) = uidropdown(comp.GridLayout);
                comp.UnitDropDown(i).Items = {'unit 1', 'unit 2', 'unit 3'};
                comp.UnitDropDown(i).ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @UnitDropDownValueChanged, true);
                comp.UnitDropDown(i).Layout.Row = 1;
                comp.UnitDropDown(i).Layout.Column = 2 + i;
                comp.UnitDropDown(i).Value = 'unit 1';
                comp.UnitDropDown(i).UserData = i;
                addStyle(comp.UnitDropDown(i), style);
            end
            %importunits(comp)
            comp.update;
        end

        % Value changed function: UnitDropDown
        function UnitDropDownValueChanged(comp, event)
            i = event.Source.UserData;
            j = comp.UnitDropDown(i).ValueIndex;
            type = Unit.unitTypes.tostr(comp.unitType(i));
            comp.subUnit(i) = comp.unitArray.(type)(j);
            comp.unit = Unit(comp.subUnit, comp.dimension);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(comp, event)
            comp.value = comp.unit.remove(comp.EditField.Value);
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            import Unit.*

            comp.EditField.Value = comp.unit.apply(comp.value);
            comp.EditFieldLabel.Text = comp.name;
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 500 22];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.UnitDropDown = uidropdown(comp.GridLayout);
            comp.UnitDropDown.Items = {'unit 1', 'unit 2', 'unit 3'};
            comp.UnitDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @UnitDropDownValueChanged, true);
            comp.UnitDropDown.Value = 'unit 1';
            comp.EditField = uieditfield(comp.GridLayout, 'numeric');
            comp.EditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @EditFieldValueChanged, true);
            comp.EditField.Editable = 'off';
            comp.EditFieldLabel = uilabel(comp.GridLayout);
            comp.EditFieldLabel.Text = 'Edit Field';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end