classdef EditFieldWithUnit < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout      matlab.ui.container.GridLayout
        RangedCheckBox  matlab.ui.control.CheckBox
        EditFieldLabel  matlab.ui.control.Label
        EditField_1     matlab.ui.control.NumericEditField
        EditField_2     matlab.ui.control.NumericEditField
        UnitDropDown    matlab.ui.control.DropDown
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        rangedisabled
        rangeswitched
    end

    properties (Access = private)
        disableRangeCounter (1,1) double {mustBeInteger(disableRangeCounter)} = 0; % used to automatically disable range after one other edit field in an app turns range on.
        unit Unit = Unit('Units\Length\meter.unit');
        unitArray
    end
    
    properties (Access = public)
        defaultUnitIndex double {mustBeInteger(defaultUnitIndex)} = 1;
        dimension double {mustBeInteger(dimension)} = 1;
        isRanged (1,1) logical = 0;
        limits double
        name string = "Edit Field";
        propertyName (1,1) string
        subUnit Unit
        unitType double {mustBeInteger(unitType)} = 1;
        value (1,2) double = [0,1];
    end
    
    methods (Access = public)
        function enable(comp)
            comp.EditFieldLabel.Enable = true;
            comp.EditField_1.Enable = true;
            if comp.isRanged
            comp.EditField_2.Enable = true;
            end
            comp.RangedCheckBox.Enable = true;
            for i = 1 : size(comp.unitType, 2)
                comp.UnitDropDown(i).Enable = true;
            end
        end

        function disable(comp)
            comp.EditFieldLabel.Enable = false;
            comp.EditField_1.Enable = false;
            comp.EditField_2.Enable = false;
            comp.RangedCheckBox.Enable = false;
            for i = 1 : size(comp.unitType, 2)
                comp.UnitDropDown(i).Enable = false;
            end
        end

        function updateunits(comp, unitArray, index)
            arguments
                comp;
                unitArray;
                index = comp.defaultUnitIndex;
            end
            comp.unitArray = unitArray;
            for i = 1 : size(comp.unitType, 2)
                comp.UnitDropDown(i).Items = cell.empty;
                type = Unit.unitTypes.tostr(comp.unitType(i));
                for unit = unitArray.(type)
                    comp.UnitDropDown(i).Items{end + 1} = convertStringsToChars(unit.getlatex(comp.dimension(i)));
                end
                comp.UnitDropDown(i).ValueIndex = index(i);
                comp.subUnit(i) = unitArray.(type)(index(i));
            end
            comp.unit = Unit(comp.subUnit, comp.dimension);
        end

        function disablerange(comp)
            if comp.disableRangeCounter == 1
                comp.disableRangeCounter = 0;
                comp.isRanged = false;
            elseif comp.disableRangeCounter == 2
                comp.disableRangeCounter = 1;
            end
        end

        function reSetupFcn(comp)
            n = size(comp.unitType, 2);
            comp.GridLayout.ColumnWidth = [{160, '2x', 30, '2x'}, num2cell(ones(1, n) * 60)];
            style = uistyle("Interpreter", "latex");
            for i = 1 : n
                comp.UnitDropDown(i) = uidropdown(comp.GridLayout);
                comp.UnitDropDown(i).Items = {'unit 1', 'unit 2', 'unit 3'};
                comp.UnitDropDown(i).ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @UnitDropDownValueChanged, true);
                comp.UnitDropDown(i).Layout.Row = 1;
                comp.UnitDropDown(i).Layout.Column = 4 + i;
                comp.UnitDropDown(i).Value = 'unit 1';
                comp.UnitDropDown(i).UserData = i;
                addStyle(comp.UnitDropDown(i), style);
            end
            comp.EditFieldLabel.Text = comp.name;
            %importunits(comp)
        end
%{
        function importunits(app)
            import Unit.*

            folders = strtrim(string(ls('Units')));
            folders = ("Units\" + folders(3 : end))';
            for folder = folders
                files = strtrim(string(ls(folder)));
                files = (folder + "\" + files(3 : end))';
                i = 1;
                for file = files
                    unit = Unit(file);
                    unitArray.(unitTypes().tostr(unit.gettype))(i) = unit;
                    i = i + 1;
                end
            end
            updateunits(app, unitArray);
        end
%}
        
        function enablerange(comp)
            comp.RangedCheckBox.Value = true;
            comp.RangedCheckBoxValueChanged;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)

        end

        % Value changed function: UnitDropDown
        function UnitDropDownValueChanged(comp, event)
            i = event.Source.UserData;
            j = comp.UnitDropDown(i).ValueIndex;
            type = Unit.unitTypes.tostr(comp.unitType(i));
            comp.subUnit(i) = comp.unitArray.(type)(j);
            comp.unit = Unit(comp.subUnit, comp.dimension);
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(comp, event)
            comp.value(2) = min( [comp.unit.remove(comp.EditField_2.Value), comp.limits(2)] );
        end

        % Value changed function: EditField_1
        function EditField_1ValueChanged(comp, event)
            comp.value(1) = max( [comp.unit.remove(comp.EditField_1.Value), comp.limits(1)] );
        end

        % Value changed function: RangedCheckBox
        function RangedCheckBoxValueChanged(comp, event)
            comp.isRanged = comp.RangedCheckBox.Value;
            if comp.isRanged == true
                comp.disableRangeCounter = 2;
                notify(comp, 'rangeswitched');
            elseif comp.isRanged == false
                comp.disableRangeCounter = 0;
                notify(comp, 'rangedisabled');
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            import Unit.*

            comp.EditFieldLabel.Text = comp.name;
            try
            comp.EditField_1.Value = comp.unit.apply(comp.value(1));
            comp.EditField_2.Value = comp.unit.apply(comp.value(2));
            catch
            comp.EditField_1.Value = comp.value(1);
            comp.EditField_2.Value = comp.value(2);
            end
            if comp.isRanged == false
                comp.EditField_2.Enable = false;
                comp.RangedCheckBox.Value = false;
            else
                comp.EditField_2.Enable = true;
                comp.RangedCheckBox.Value = true;
            end
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 500 22];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.UnitDropDown = uidropdown(comp.GridLayout);
            comp.UnitDropDown.Items = {'unit 1', 'unit 2', 'unit 3'};
            comp.UnitDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @UnitDropDownValueChanged, true);
            comp.UnitDropDown.Value = 'unit 1';
            comp.EditField_2 = uieditfield(comp.GridLayout, 'numeric');
            comp.EditField_2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @EditField_2ValueChanged, true);
            comp.EditField_1 = uieditfield(comp.GridLayout, 'numeric');
            comp.EditField_1.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @EditField_1ValueChanged, true);
            comp.EditFieldLabel = uilabel(comp.GridLayout);
            comp.EditFieldLabel.Text = 'Edit Field';
            comp.RangedCheckBox = uicheckbox(comp.GridLayout);
            comp.RangedCheckBox.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @RangedCheckBoxValueChanged, true);
            comp.RangedCheckBox.Text = 'to';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end