classdef OutputTable < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout  matlab.ui.container.GridLayout
        UITable     matlab.ui.control.Table
    end

    properties (Access = private)
        nColumns
        nRows (1,1) double = 0;
        position (1,1) double = 1;
        style
        symbolColumn
        tableSize (1,1) double = 15;
        unitArray
        unitColumn
    end
    
    properties (Access = public)
        columnNameArray string
        dimensionColumn
        propertyNameColumn string
        unitTypeColumn
        values
    end
    
    
    methods (Access = private)
        
        function results = func(comp)
            
        end
    end
    
    methods (Access = public)
        function loaddata(comp, columnNameArray, propertyNameArray, valueArray, unitTypeArray, dimensionArray, preferredUnitArray)

            comp.nRows = size(propertyNameArray, 2);
            comp.nColumns = size(columnNameArray, 2) + 3;
            comp.columnNameArray = columnNameArray;
            comp.UITable.ColumnName = columnNameArray;
            comp.propertyNameColumn = propertyNameArray;
            comp.UITable.RowName = comp.propertyNameColumn;
            comp.values = table(valueArray);
            comp.UITable.Data = table(valueArray);
            comp.unitTypeColumn = unitTypeArray;
            comp.dimensionColumn = dimensionArray;
            for row = 1 : comp.nRows
                for column = 1 : 3
                    dimension = comp.dimensionColumn{row, column};
                    if ~isempty(dimension)
                        unitType = Unit.unitTypes.tostr(comp.unitTypeColumn{row, column});
                        symbolArray = string.empty;
                        for unit = comp.unitArray.(unitType)
                            symbolArray(end + 1) = unit.getlatex(dimension);
                        end
                        preferredUnit = preferredUnitArray{row, column};
                        comp.symbolColumn{row, column} = categorical(symbolArray(preferredUnit));
                        comp.symbolColumn{row, column} = setcats(comp.symbolColumn{row, column}, symbolArray);
                    end
                end
            end
            addStyle(comp.UITable, comp.style,"column", comp.nColumns - 2 : comp.nColumns);
        end

        function updatetabledisplay(comp)
            for row = 1 : comp.nRows
                for column = 1 : 3
                    a = comp.symbolColumn(row, column);
                    comp.UITable.Data(row, column + comp.nColumns) = a;
                end
            end
        end

        function updateunits(comp, unitArray)
            comp.unitArray = unitArray;
        end

        function importunits(comp)
            import Unit.*

            folders = strtrim(string(ls('Units')));
            folders = ("Units\" + folders(3 : end))';
            for folder = folders
                files = strtrim(string(ls(folder)));
                files = (folder + "\" + files(3 : end))';
                i = 1;
                for file = files
                    unit = Unit(file);
                    unitTypes().tostr(unit.gettype);
                    unitArray.(unitTypes().tostr(unit.gettype))(i) = unit;
                    i = i + 1;
                end
            end
            updateunits(comp, unitArray);
        end

        function generateTestData(comp)
            i = 0;
            propertyNameArray = string.empty;
            nColumns = 5;
            columnNameArray = 1 : nColumns;
            unitTypeArray = cell.empty;
            preferredUnitArray = cell.empty;
            dimensionArray = cell.empty;
            for letter = 'a' : 'd'
                i = i + 1;
                propertyNameArray(end + 1) = string([letter,letter,letter,letter,letter]);
                a = (1 + rand([nColumns,1])) * sqrt(i);

                valueArray(i,:) = mat2cell(a, ones(1,nColumns), 1);
                unitTypeArray(i, 1:3) = cell(1, 3);
                preferredUnitArray(i, 1:3) = cell(1, 3);
                dimensionArray(i, 1:3) = cell(1, 3);
                dimensionArray{i, 1} = randi([-2,3]);
                if dimensionArray{i, 1} ~= 0
                    unitTypeArray{i, 1} = randi([1,10]);
                    preferredUnitArray{i, 1} = randi([1,2]);
                    dimensionArray{i, 2} = randi([-2,3]);
                    if dimensionArray{i, 2} ~= 0
                        unitTypeArray{i, 2} = randi([1,10]);
                        preferredUnitArray{i, 2} = randi([1,2]);
                        dimensionArray{i, 3} = randi([-2,3]);
                        if dimensionArray{i, 3} ~= 0
                            unitTypeArray{i, 3} = randi([1,10]);
                            preferredUnitArray{i, 3} = randi([1,2]);
                        else
                            dimensionArray{i, 3} = [];
                        end
                    else
                        dimensionArray{i, 2} = [];
                    end
                else
                    dimensionArray{i, 1} = [];
                end
            end

            comp.loaddata(columnNameArray, propertyNameArray, valueArray, unitTypeArray, dimensionArray, preferredUnitArray);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            comp.style = uistyle("Interpreter", "latex");
            comp.importunits;
            comp.generateTestData;
            comp.updatetabledisplay
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            

        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 640 480];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.UITable = uitable(comp.GridLayout);
            comp.UITable.ColumnName = {'1'; '2'; '3'; '4'};
            comp.UITable.RowName = {};
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end