classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        NewMenu_2                       matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        SaveasMenu                      matlab.ui.container.Menu
        ImportMenu                      matlab.ui.container.Menu
        PropellantMenu                  matlab.ui.container.Menu
        PropellantmixtureMenu           matlab.ui.container.Menu
        EnginedefinitionMenu            matlab.ui.container.Menu
        AtmosphericcompositionMenu      matlab.ui.container.Menu
        UnitdefinitionsMenu             matlab.ui.container.Menu
        ExportMenu                      matlab.ui.container.Menu
        PropellantMenu_2                matlab.ui.container.Menu
        PropellantmixtureMenu_2         matlab.ui.container.Menu
        GeometrydefinitionsMenu_2       matlab.ui.container.Menu
        AtmosphericcompositionMenu_2    matlab.ui.container.Menu
        UnitdefinitionsMenu_2           matlab.ui.container.Menu
        UnitsMenu                       matlab.ui.container.Menu
        LengthMenu                      matlab.ui.container.Menu
        mMenu                           matlab.ui.container.Menu
        mmMenu                          matlab.ui.container.Menu
        inMenu                          matlab.ui.container.Menu
        ftMenu                          matlab.ui.container.Menu
        MassMenu                        matlab.ui.container.Menu
        kgMenu                          matlab.ui.container.Menu
        gMenu                           matlab.ui.container.Menu
        tMenu                           matlab.ui.container.Menu
        lbMenu                          matlab.ui.container.Menu
        SpeedMenu                       matlab.ui.container.Menu
        msMenu                          matlab.ui.container.Menu
        kmhMenu                         matlab.ui.container.Menu
        ftsMenu                         matlab.ui.container.Menu
        imhMenu                         matlab.ui.container.Menu
        ForceMenu                       matlab.ui.container.Menu
        PressureMenu                    matlab.ui.container.Menu
        TemperatureMenu                 matlab.ui.container.Menu
        EnergyMenu                      matlab.ui.container.Menu
        PowerMenu                       matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        ResidualsPanel                  matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        DEBUGButton                     matlab.ui.control.Button
        ThroatPressureEditField_5       matlab.ui.control.NumericEditField
        ThroatPressureEditFieldLabel_5  matlab.ui.control.Label
        ThroatPressureEditField_4       matlab.ui.control.NumericEditField
        ThroatPressureEditFieldLabel_4  matlab.ui.control.Label
        ThroatPressureEditField_3       matlab.ui.control.NumericEditField
        ThroatPressureEditFieldLabel_3  matlab.ui.control.Label
        ThroatPressureEditField_2       matlab.ui.control.NumericEditField
        ThroatPressureEditFieldLabel_2  matlab.ui.control.Label
        ThroatPressureEditField         matlab.ui.control.NumericEditField
        ThroatPressureEditFieldLabel    matlab.ui.control.Label
        OutputsPanel                    matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        SummaryTab                      matlab.ui.container.Tab
        GraphingTab                     matlab.ui.container.Tab
        ExecuteButton                   matlab.ui.control.Button
        PropellantsPanel                matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        GridLayout11                    matlab.ui.container.GridLayout
        SpeciesTable                    matlab.ui.control.Table
        GridLayout9                     matlab.ui.container.GridLayout
        ConfirmSpeciesButton            matlab.ui.control.Button
        MoveUpSpeciesButton             matlab.ui.control.Button
        MoveDownSpeciesButton           matlab.ui.control.Button
        RemoveSpeciesButton_2           matlab.ui.control.Button
        AddSpeciesButton                matlab.ui.control.Button
        PropellantLabel                 matlab.ui.control.Label
        GridLayout10                    matlab.ui.container.GridLayout
        MixturecompositionLabel         matlab.ui.control.Label
        GridLayout8                     matlab.ui.container.GridLayout
        AddPropButton                   matlab.ui.control.Button
        MoveDownPropButton              matlab.ui.control.Button
        MoveUpPropButton                matlab.ui.control.Button
        RemovePropButton                matlab.ui.control.Button
        PropTable                       matlab.ui.control.Table
        AtmospherePanel                 matlab.ui.container.Panel
        GridLayout2_2                   matlab.ui.container.GridLayout
        GridLayout7                     matlab.ui.container.GridLayout
        CompositionLabel                matlab.ui.control.Label
        GridLayout9_2                   matlab.ui.container.GridLayout
        ConfirmAtmoButton               matlab.ui.control.Button
        MoveDownAtmoButton              matlab.ui.control.Button
        MoveUpAtmoButton                matlab.ui.control.Button
        RemoveAtmoButton                matlab.ui.control.Button
        AddAtmoButton                   matlab.ui.control.Button
        AtmoTable                       matlab.ui.control.Table
        GridLayout6_4                   matlab.ui.container.GridLayout
        AtmoTemperatureEditField        matlab.ui.control.NumericEditField
        TemperatureEditFieldLabel       matlab.ui.control.Label
        AtmoPressureEditField           matlab.ui.control.NumericEditField
        PressureEditFieldLabel          matlab.ui.control.Label
        ThermodynamicInfoLabel          matlab.ui.control.Label
        AtmoTemperatureUnitLabel        matlab.ui.control.Label
        AtmoPressureUnitLabel           matlab.ui.control.Label
        EngineDefinitionsPanel          matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        GridLayout6_5                   matlab.ui.container.GridLayout
        InjectionPressureUnitLabel      matlab.ui.control.Label
        InjectionTemperatureUnitLabel   matlab.ui.control.Label
        InjectionPressureEditField      matlab.ui.control.NumericEditField
        PressureEditField_2Label_2      matlab.ui.control.Label
        InjectionTemperatureEditField   matlab.ui.control.NumericEditField
        TemperatureEditFieldLabel_2     matlab.ui.control.Label
        InjectionDefinitionsLabel       matlab.ui.control.Label
        GridLayout6_3                   matlab.ui.container.GridLayout
        ChamberPressureUnitLabel        matlab.ui.control.Label
        HeatingElementOutputUnitLabel   matlab.ui.control.Label
        ChamberPressureEditField        matlab.ui.control.NumericEditField
        PressureEditField_2Label        matlab.ui.control.Label
        HeatingElementOutputEditField   matlab.ui.control.NumericEditField
        HeatingElementOutputEditFieldLabel  matlab.ui.control.Label
        ChamberDefinitionsLabel         matlab.ui.control.Label
        GridLayout6_2                   matlab.ui.container.GridLayout
        NozzleAreaUnitLabel             matlab.ui.control.Label
        ThroatAreaUnitLabel             matlab.ui.control.Label
        ChamberAreaUnitLabel            matlab.ui.control.Label
        NozzleAreaEditField             matlab.ui.control.NumericEditField
        NozzleExitEditField_2Label      matlab.ui.control.Label
        ThroatAreaEditField             matlab.ui.control.NumericEditField
        ThroatEditField_2Label          matlab.ui.control.Label
        ChamberAreaEditField            matlab.ui.control.NumericEditField
        ChamberEditField_2Label         matlab.ui.control.Label
        AreasLabel                      matlab.ui.control.Label
        GridLayout6                     matlab.ui.container.GridLayout
        NozzleDiameterUnitLabel         matlab.ui.control.Label
        ThroatDiameterUnitLabel         matlab.ui.control.Label
        ChamberDiameterUnitLabel        matlab.ui.control.Label
        NozzleDiameterEditField         matlab.ui.control.NumericEditField
        NozzleExitEditFieldLabel        matlab.ui.control.Label
        ThroatDiameterEditField         matlab.ui.control.NumericEditField
        ThroatEditFieldLabel            matlab.ui.control.Label
        ChamberDiameterEditField        matlab.ui.control.NumericEditField
        ChamberEditFieldLabel           matlab.ui.control.Label
        DiametersLabel                  matlab.ui.control.Label
        ContextMenu                     matlab.ui.container.ContextMenu
        NewMenu                         matlab.ui.container.Menu
        PredefinedMenu                  matlab.ui.container.Menu
        Menu                            matlab.ui.container.Menu
    end

    properties (Access = private)

        % Imported data
        phase_imported;
        engine_imported;
        units_imported;

        % Geometry definitions
        d_cc = 0; % Chamber diameter
        d_th = 0; % Throat diameter
        d_ex = 0; % Exit diameter
        A_cc = 0; % Chamber Area
        A_th = 0; % Throat Area
        A_ex = 0; % Exit Area

        %Injection definitions
        temperature_in = 300
        pressure_in = 10 * oneatm

        %Chamber definitions
        Q_cc = 0;
        pressure_cc = 1e6;

        % Atmospheric conditions
        temperature_at = 288.15;
        pressure_at = oneatm;
        atmo = GRI30;
        atmo_name = string.empty;
        atmo_ratio = double.empty;
        atmo_denominator = 1;

        % Propellants
        prop = GRI30;
        prop_ratio = 1;
        prop_denominator = 1;
        prop_index = 1;

        % Outputs
        prop_mixture;
        probingPoint_converging;
        probingPoint_diverging;

        % Propellant species temporary data
        species_name = string.empty;
        species_ratio = double.empty;

        % Unit definitiions
        UnitDialog;

        unit_length_id = 1;

        unit_length = [1, 1e-3, 0.0254, 0.3048];
        unit_length_name = ["m", "mm", "in", "ft"];

        unit_mass_id = 1;

        unit_mass = [1, 1e-3, 1e3, 0.45359237];
        unit_mass_name = ["kg", "g", "t", "lb"];

        unit_speed_id = 1;

        unit_speed = [1, 1/3.6, 0.3408, 0.44704];
        unit_speed_name = ["m/s", "km/h", "ft/s", "mi/h"];

        unit_force_id = 1;

        unit_force = [1, 1e3, 9.81e3, 4.44822162];
        unit_force_name = ["N", "kN", "tf", "lbf"];

        unit_pressure_id = 1;

        unit_pressure = [1, 1e3, 1e6, 1e5, 101325, 6894.75729];
        unit_pressure_name = ["Pa", "kPa", "MPa", "bar", "atm", "psi"];

        unit_temperature_id = 1;

        unit_temperature_muliplier = [1, 1, 5/9, 5/9];
        unit_temperature_offset = [0, -273.15, -459.67 * 5 / 9, 0];
        unit_temperature_name = ["K", "°C", "°F", "°R"];

        unit_energy_id = 1;

        unit_energy = [1, 1e3, 4.18400, 4.184e3];
        unit_energy_name = ["J", "kJ", "cal", "kcal"];

        unit_power_id = 1;

        unit_power = [1, 1e3, 746];
        unit_power_name = ["W", "kW", "hp"];

        use_molar_weight = true;

    end

    methods (Access = private)

        % Calculates an area of a circle
        function A_circle = A_circle(~, d_circle)
            A_circle = d_circle ^ 2 / 4 * pi;
        end

        % Calculates a diameter of a circle
        function d_circle = d_circle(~, A_circle)
            d_circle = sqrt(A_circle / pi) * 2;
        end

        % Resets all displays
        function ValueRefreshDisp(app)
            app.ChamberDiameterEditField.Value = app.d_cc / app.unit_length(app.unit_length_id);
            app.ThroatDiameterEditField.Value = app.d_th / app.unit_length(app.unit_length_id);
            app.NozzleDiameterEditField.Value = app.d_ex / app.unit_length(app.unit_length_id);
            app.ChamberDiameterUnitLabel.Text = app.unit_length_name(app.unit_length_id);
            app.ThroatDiameterUnitLabel.Text = app.unit_length_name(app.unit_length_id);
            app.NozzleDiameterUnitLabel.Text = app.unit_length_name(app.unit_length_id);
            app.ChamberAreaEditField.Value = app.A_cc / app.unit_length(app.unit_length_id) ^ 2;
            app.ThroatAreaEditField.Value = app.A_th / app.unit_length(app.unit_length_id) ^ 2;
            app.NozzleAreaEditField.Value = app.A_ex / app.unit_length(app.unit_length_id) ^ 2;
            app.ChamberAreaUnitLabel.Text = string(app.unit_length_name(app.unit_length_id) + "^{2}");
            app.ThroatAreaUnitLabel.Text = string(app.unit_length_name(app.unit_length_id) + "^{2}");
            app.NozzleAreaUnitLabel.Text = string(app.unit_length_name(app.unit_length_id) + "^{2}");
            app.AtmoPressureEditField.Value = app.pressure_at / app.unit_pressure(app.unit_pressure_id);
            app.AtmoPressureUnitLabel.Text = app.unit_pressure_name(app.unit_pressure_id);
            app.AtmoTemperatureEditField.Value = (app.temperature_at + app.unit_temperature_offset(app.unit_temperature_id)) / app.unit_temperature_muliplier(app.unit_temperature_id);
            app.AtmoTemperatureUnitLabel.Text = app.unit_temperature_name(app.unit_temperature_id);
            app.InjectionPressureEditField.Value = app.pressure_in / app.unit_pressure(app.unit_pressure_id);
            app.InjectionPressureUnitLabel.Text = app.unit_pressure_name(app.unit_pressure_id);
            app.InjectionTemperatureEditField.Value = (app.temperature_in + app.unit_temperature_offset(app.unit_temperature_id)) / app.unit_temperature_muliplier(app.unit_temperature_id);
            app.InjectionTemperatureUnitLabel.Text = app.unit_temperature_name(app.unit_temperature_id);
            app.HeatingElementOutputEditField.Value = app.Q_cc / app.unit_power(app.unit_power_id);
            app.HeatingElementOutputUnitLabel.Text = app.unit_power_name(app.unit_power_id);
            app.ChamberPressureEditField.Value = app.pressure_cc / app.unit_pressure(app.unit_pressure_id);
            app.ChamberPressureUnitLabel.Text = app.unit_pressure_name(app.unit_pressure_id);
        end

        function PropTableRefreshDisp(app)
            Table = table();
            for i = 1 : size(app.prop, 2)
                Table(i, 1) = table(string(name(app.prop(i))));
                Table(i, 2) = table(app.prop_ratio(i));
            end
            app.PropTable.Data = Table;
        end

        function SpeciesTableRegenerateData(app)
            index = app.prop_index;
            nZeros = 0;
            app.species_name = string(zeros(1, 1));
            app.species_ratio = zeros(1, 1);
            for i = 1 : nSpecies(app.prop(index))
                moleFraction_temp = moleFraction(app.prop(index), speciesName(app.prop(index), i));
                if moleFraction_temp > 0
                    app.species_name(i - nZeros) = string(speciesName(app.prop(index), i));
                    app.species_ratio(i - nZeros) = moleFraction_temp * app.prop_denominator(index);
                else
                    nZeros = nZeros + 1;
                end
            end
        end

        function SpeciesTableRefreshDisp(app)
            Table = table();
            for i = 1 : size(app.species_name, 2)
                Table(i, 1) = table(app.species_name(i));
                Table(i, 2) = table(app.species_ratio(i));
            end
            app.SpeciesTable.Data = Table;
        end

        function AtmoTableRegenerateData(app)
            nZeros = 0;
            app.atmo_name = string(zeros(1, 1));
            app.atmo_ratio = zeros(1, 1);
            for i = 1 : nSpecies(app.atmo)
                moleFraction_temp = moleFraction(app.atmo, speciesName(app.atmo, i));
                if moleFraction_temp > 0
                    app.atmo_name(i - nZeros) = string(speciesName(app.atmo, i));
                    app.atmo_ratio(i - nZeros) = moleFraction_temp * app.atmo_denominator;
                else
                    nZeros = nZeros + 1;
                end
            end
        end

        function AtmoTableRefreshDisp(app)
            Table = table();
            for i = 1 : size(app.atmo_name, 2)
                Table(i, 1) = table(app.atmo_name(i));
                Table(i, 2) = table(app.atmo_ratio(i));
            end
            app.AtmoTable.Data = Table;
        end

        function AtmoReset(app)
            set(app.atmo, 'X', 'N2:78.084,O2:20.946,AR:0.9340,CO2:0.0417');
            app.AtmoTableRegenerateData();
            app.AtmoTableRefreshDisp();
        end

        function phase = PhaseCopy(~, phase_original)
            phase_copy = GRI30;
            set(phase_copy, 'T', temperature(phase_original), 'P', pressure(phase_original), 'X', moleFractions(phase_original));
            phase = phase_copy;
        end

        function phase = PhaseCombine(~, phases_original, ratioes)
            phase = GRI30;
            pressure_partial = zeros([size(phases_original, 2), 1], "double");
            mole_fractions_new = zeros([nSpecies(phases_original(1)), 1], "double");
            internal_energy = 0;
            for i = 1 : size(phases_original, 2)
                mole_fractions_new = mole_fractions_new + moleFractions(phases_original(i)) * ratioes(i) * pressure(phases_original(i));
                pressure_partial(i) = pressure(phases_original(i)) * ratioes(i) / sum(ratioes);
            end
            pressure_total = sum(pressure_partial);
            for i = 1 : size(phases_original, 2)
                internal_energy = internal_energy + intEnergy_mole(phases_original(i)) * pressure_partial(i) / pressure_total;
                temperature_props(i) = temperature(phases_original(i));
            end
            setMoleFractions(phase, mole_fractions_new);
            temperature_min = min(temperature_props);
            temperature_max = max(temperature_props);
            while 1
                temperature_final = (temperature_min + temperature_max) / 2;
                set(phase, 'P', pressure_total, 'T', temperature_final);
                internal_energy_calc = intEnergy_mole(phase);
                error = (internal_energy_calc - internal_energy) / abs(internal_energy_calc);
                if abs(error) < 1e-6
                    break;
                end
                if error < 0
                    temperature_min = temperature_final;
                else
                    temperature_max = temperature_final;
                end

            end
        end

        function Injection(~, prop, temperature_in, pressure_in)
            set(prop, 'X', 'CH4:280,O2:510', 'P', pressure_in, 'T', temperature_in);
        end

        function Combustion(~, prop, heat_power, mass_rate)
            if nargin == 3
                temperature_delta = heat_power / (mass_rate * cp_mass(prop));
                setTemperature(prop, temperature(prop) + temperature_delta);
            end
            equilibrate(app, prop, 'HP');
        end

        function phase = PhaseImport(source)

        end

        function EngineImport(source)

        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartUp(app, arg1)
            setName(app.prop, 'Hydrogen');
            app.PropTableRefreshDisp();
            app.AtmoReset();
            app.AtmoTableRegenerateData();
            app.AtmoTableRefreshDisp();
            app.ValueRefreshDisp();
        end

        % Value changed function: ChamberDiameterEditField
        function ChamberDiameterSet(app, event)
            app.d_cc = app.ChamberDiameterEditField.Value * app.unit_length(app.unit_length_id);
            app.A_cc = app.A_circle(app.d_cc);
            app.ChamberAreaEditField.Value = app.A_cc / app.unit_length(app.unit_length_id) ^ 2;
        end

        % Value changed function: ThroatDiameterEditField
        function ThroatDiameterSet(app, event)
            app.d_th = app.ThroatDiameterEditField.Value * app.unit_length(app.unit_length_id);
            app.A_th = app.A_circle(app.d_th);
            app.ThroatAreaEditField.Value = app.A_th / app.unit_length(app.unit_length_id) ^ 2;
        end

        % Value changed function: NozzleDiameterEditField
        function ExitDiameterSet(app, event)
            app.d_ex = app.NozzleDiameterEditField.Value * app.unit_length(app.unit_length_id);
            app.A_ex = app.A_circle(app.d_ex);
            app.NozzleAreaEditField.Value = app.A_ex / app.unit_length(app.unit_length_id) ^ 2;
        end

        % Value changed function: ChamberAreaEditField
        function ChamberAreaSet(app, event)
            app.A_cc = app.ChamberAreaEditField.Value * app.unit_length(app.unit_length_id) ^ 2;
            app.d_cc = app.d_circle(app.A_cc);
            app.ChamberDiameterEditField.Value = app.d_cc / app.unit_length(app.unit_length_id);
        end

        % Value changed function: ThroatAreaEditField
        function ThroatAreaSet(app, event)
            app.A_th = app.ThroatAreaEditField.Value * app.unit_length(app.unit_length_id) ^ 2;
            app.d_th = app.d_circle(app.A_th);
            app.ThroatDiameterEditField.Value = app.d_th / app.unit_length(app.unit_length_id);
        end

        % Value changed function: NozzleAreaEditField
        function ExitAreaSet(app, event)
            app.A_ex = app.NozzleAreaEditField.Value * app.unit_length(app.unit_length_id) ^ 2;
            app.d_ex = app.d_circle(app.A_ex);
            app.NozzleDiameterEditField.Value = app.d_ex / app.unit_length(app.unit_length_id);
        end

        % Value changed function: HeatingElementOutputEditField
        function HeatingElementOutputSet(app, event)
            app.Q_cc = app.HeatingElementOutputEditField.Value * app.unit_power(app.unit_power_id);
        end

        % Value changed function: ChamberPressureEditField
        function ChamberPressureSet(app, event)
            app.pressure_cc = app.ChamberPressureEditField.Value * app.unit_pressure(app.unit_pressure_id);
        end

        % Button pushed function: AddPropButton
        function PropAdd(app, event)
            app.prop(size(app.prop, 2) + 1) = GRI30;
            setName(app.prop(size(app.prop, 2)), convertStringsToChars("Propellant" + size(app.prop, 2)));
            app.prop_ratio(size(app.prop, 2)) = 1;
            app.prop_denominator(size(app.prop, 2)) = 1;
            app.PropTableRefreshDisp();
        end

        % Button pushed function: RemovePropButton
        function PropRemove(app, event)
            if size(app.PropTable.Selection, 1) ~= 1
                return;
            elseif ismember(app.PropTable.Selection(1), 1 : size(app.prop, 2))
                index = app.PropTable.Selection(1);
            else
                return;
            end
            if app.PropTable.Selection(1) == size(app.prop, 2) && size(app.prop, 2) ~= 1
                app.PropTable.Selection(1) = app.PropTable.Selection(1) - 1;
            end
            app.prop(index) = [];
            app.prop_ratio(index) = [];
            app.prop_denominator(index) = [];
            app.PropTableRefreshDisp();
            if size(app.PropTable.Selection, 1) ~= 1
                return;
            end
            app.prop_index = app.PropTable.Selection(1);
            app.SpeciesTableRegenerateData();
            app.SpeciesTableRefreshDisp();
        end

        % Cell selection callback: PropTable
        function PropSelect(app, event)
            if size(app.PropTable.Selection, 1) ~= 1
                return;
            elseif ismember(app.PropTable.Selection(1), 1 : size(app.prop, 2))
                index = app.PropTable.Selection(1);
            else
                return;
            end
            app.PropellantLabel.Text = string(name(app.prop(index)));
            app.prop_index = app.PropTable.Selection(1);
            app.SpeciesTableRegenerateData();
            app.SpeciesTableRefreshDisp();
        end

        % Button pushed function: MoveUpPropButton
        function PropMoveUp(app, event)
            if size(app.PropTable.Selection, 1) ~= 1
                return;
            elseif ismember(app.PropTable.Selection(1), 2 : size(app.prop, 2))
                index = app.PropTable.Selection(1);
            else
                return;
            end
            if app.PropTable.Selection(1) ~= 1 && size(app.prop, 2) ~= 1
                app.PropTable.Selection(1) = app.PropTable.Selection(1) - 1;
            end
            app.prop([index; index - 1]) = app.prop([index - 1; index]);
            app.prop_ratio([index; index - 1]) = app.prop_ratio([index - 1; index]);
            app.prop_denominator([index; index - 1]) = app.prop_denominator([index - 1; index]);
            app.PropTableRefreshDisp();
            app.prop_index = app.PropTable.Selection(1);
            app.SpeciesTableRegenerateData();
            app.SpeciesTableRefreshDisp();
        end

        % Button pushed function: MoveDownPropButton
        function PropMoveDown(app, event)
            if size(app.PropTable.Selection, 1) ~= 1
                return;
            elseif ismember(app.PropTable.Selection(1), 1 : size(app.prop, 2) - 1)
                index = app.PropTable.Selection(1);
            else
                return;
            end
            if app.PropTable.Selection(1) ~= size(app.prop, 2) && size(app.prop, 2) ~= 1
                app.PropTable.Selection(1) = app.PropTable.Selection(1) + 1;
            end
            app.prop([index; index + 1]) = app.prop([index + 1; index]);
            app.prop_ratio([index; index + 1]) = app.prop_ratio([index + 1; index]);
            app.prop_denominator([index; index + 1]) = app.prop_denominator([index + 1; index]);
            app.PropTableRefreshDisp();
            app.prop_index = app.PropTable.Selection(1);
            app.SpeciesTableRegenerateData();
            app.SpeciesTableRefreshDisp();
        end

        % Cell edit callback: PropTable
        function PropEdit(app, event)
            indices = event.Indices;
            index = indices(1);
            type = indices(2);
            newData = event.NewData;
            if type == 1
                setName(app.prop(index), convertStringsToChars(newData));
            else
                if ~isnan(double(newData))
                    app.prop_ratio(index) = double(newData);
                end
            end
            app.PropTableRefreshDisp();
        end

        % Button pushed function: AddSpeciesButton
        function SpeciesAdd(app, event)
            app.species_name(size(app.species_name, 2) + 1) = 'H2';
            app.species_ratio(size(app.species_name, 2) + 1) = 0;
            app.SpeciesTableRefreshDisp();
        end

        % Button pushed function: RemoveSpeciesButton_2
        function SpeciesRemove(app, event)
            if size(app.SpeciesTable.Selection, 1) ~= 1
                return;
            elseif ismember(app.SpeciesTable.Selection(1), 1 : size(app.species_name, 2))
                index = app.SpeciesTable.Selection(1);
            else
                return;
            end
            if app.SpeciesTable.Selection(1) == size(app.species_name, 2) && size(app.species_name, 2) ~= 1
                app.SpeciesTable.Selection(1) = app.SpeciesTable.Selection(1) - 1;
            end
            app.species_name(index) = [];
            app.species_ratio(index) = [];
            app.SpeciesTableRefreshDisp();
        end

        % Button pushed function: MoveUpSpeciesButton
        function SpeciesMoveUp(app, event)
            if size(app.SpeciesTable.Selection, 1) ~= 1
                return;
            elseif ismember(app.SpeciesTable.Selection(1), 2 : size(app.species_name, 2))
                index = app.SpeciesTable.Selection(1);
            else
                return;
            end
            if app.SpeciesTable.Selection(1) ~= 1 && size(app.species_name, 2) ~= 1
                app.SpeciesTable.Selection(1) = app.SpeciesTable.Selection(1) - 1;
            end
            app.species_name([index; index - 1]) = app.species_name([index - 1; index]);
            app.species_ratio([index; index - 1]) =  app.species_ratio([index - 1; index]);
            app.SpeciesTableRefreshDisp();
        end

        % Button pushed function: MoveDownSpeciesButton
        function SpeciesMoveDown(app, event)
            if size(app.SpeciesTable.Selection, 1) ~= 1
                return;
            elseif ismember(app.SpeciesTable.Selection(1), 1 : size(app.species_name, 2) - 1)
                index = app.SpeciesTable.Selection(1);
            else
                return;
            end
            if app.SpeciesTable.Selection(1) ~= size(app.species_name, 2) && size(app.species_name, 2) ~= 1
                app.SpeciesTable.Selection(1) = app.SpeciesTable.Selection(1) + 1;
            end
            app.species_name([index; index + 1]) = app.species_name([index + 1; index]);
            app.species_ratio([index; index + 1]) =  app.species_ratio([index + 1; index]);
            app.SpeciesTableRefreshDisp();
        end

        % Cell edit callback: SpeciesTable
        function SpeciesEdit(app, event)
            indices = event.Indices;
            index = indices(1);
            type = indices(2);
            newData = event.NewData;
            if type == 1
                if ismember(newData, speciesName(app.prop(app.prop_index), 1 : nSpecies(app.prop(app.prop_index))))
                    app.species_name(index) = newData;
                end
            else
                if ~isnan(double(newData))
                    app.species_ratio(index) = newData;
                end
            end
            app.SpeciesTableRefreshDisp();
        end

        % Cell selection callback: SpeciesTable
        function SpeciesSelect(app, event)
            if size(app.SpeciesTable.Selection, 1) ~= 1
                return;
            elseif ismember(app.SpeciesTable.Selection(1), 1 : size(app.prop, 2))
            else
                return;
            end
            app.SpeciesTableRefreshDisp();
        end

        % Button pushed function: ConfirmSpeciesButton
        function SpeciesConfirm(app, event)
            ratioes = zeros(nSpecies(app.prop(app.prop_index)), 1, 'double');
            for i = 1 : size(app.species_name, 2)
                ratioes(speciesIndex(app.prop(app.prop_index), char(app.species_name(i)))) = ratioes(speciesIndex(app.prop(app.prop_index), char(app.species_name(i)))) + app.species_ratio(i);
            end
            app.prop_denominator(app.prop_index) = sum(ratioes);
            set(app.prop(app.prop_index), 'X', ratioes);
            app.SpeciesTableRegenerateData();
            app.SpeciesTableRefreshDisp();
        end

        % Button pushed function: DEBUGButton
        function DEBUG(app, event)
            clc;
            app.ValueRefreshDisp();
            app.PropTableRefreshDisp();
            app.SpeciesTableRefreshDisp();
            disp(app.prop(app.prop_index))
            disp(app.prop_denominator(app.prop_index));
        end

        % Button pushed function: AddAtmoButton
        function AtmoAdd(app, event)
            app.atmo_name(size(app.atmo_name, 2) + 1) = 'N2';
            app.atmo_ratio(size(app.atmo_name, 2) + 1) = 0;
            app.AtmoTableRefreshDisp();
        end

        % Button pushed function: RemoveAtmoButton
        function AtmoRemove(app, event)
            if size(app.AtmoTable.Selection, 1) ~= 1
                return;
            elseif ismember(app.AtmoTable.Selection(1), 1 : size(app.atmo_name, 2))
                index = app.AtmoTable.Selection(1);
            else
                return;
            end
            if app.AtmoTable.Selection(1) == size(app.atmo_name, 2) && size(app.atmo_name, 2) ~= 1
                app.AtmoTable.Selection(1) = app.AtmoTable.Selection(1) - 1;
            end
            app.atmo_name(index) = [];
            app.atmo_ratio(index) = [];
            app.AtmoTableRefreshDisp();
        end

        % Button pushed function: MoveUpAtmoButton
        function AtmoMoveUp(app, event)
            if size(app.AtmoTable.Selection, 1) ~= 1
                return;
            elseif ismember(app.AtmoTable.Selection(1), 2 : size(app.atmo_name, 2))
                index = app.AtmoTable.Selection(1);
            else
                return;
            end
            if app.AtmoTable.Selection(1) ~= 1 && size(app.atmo_name, 2) ~= 1
                app.AtmoTable.Selection(1) = app.AtmoTable.Selection(1) - 1;
            end
            app.atmo_name([index; index - 1]) = app.atmo_name([index - 1; index]);
            app.atmo_ratio([index; index - 1]) =  app.atmo_ratio([index - 1; index]);
            app.AtmoTableRefreshDisp();
        end

        % Button pushed function: MoveDownAtmoButton
        function AtmoMoveDown(app, event)
            if size(app.AtmoTable.Selection, 1) ~= 1
                return;
            elseif ismember(app.AtmoTable.Selection(1), 1 : size(app.atmo_name, 2) - 1)
                index = app.AtmoTable.Selection(1);
            else
                return;
            end
            if app.AtmoTable.Selection(1) ~= size(app.atmo_name, 2) && size(app.atmo_name, 2) ~= 1
                app.AtmoTable.Selection(1) = app.AtmoTable.Selection(1) + 1;
            end
            app.atmo_name([index; index + 1]) = app.atmo_name([index + 1; index]);
            app.atmo_ratio([index; index + 1]) =  app.atmo_ratio([index + 1; index]);
            app.AtmoTableRefreshDisp();
        end

        % Button pushed function: ConfirmAtmoButton
        function AtmoConfirm(app, event)
            ratioes = zeros(nSpecies(app.atmo), 1, 'double');
            for i = 1 : size(app.atmo_name, 2)
                ratioes(speciesIndex(app.atmo, char(app.atmo_name(i)))) = ratioes(speciesIndex(app.atmo, char(app.atmo_name(i)))) + app.atmo_ratio(i);
            end
            app.atmo_denominator = sum(ratioes);
            set(app.atmo, 'X', ratioes);
            app.AtmoTableRegenerateData();
            app.AtmoTableRefreshDisp();
        end

        % Cell edit callback: AtmoTable
        function AtmoEdit(app, event)
            indices = event.Indices;
            index = indices(1);
            type = indices(2);
            newData = event.NewData;
            if type == 1
                if ismember(newData, atmoName(app.atmo, 1 : nSpecies(app.atmo)))
                    app.atmo_name(index) = newData;
                end
            else
                if ~isnan(double(newData))
                    app.atmo_ratio(index) = newData;
                end
            end
            app.AtmoTableRefreshDisp();
        end

        % Cell selection callback: AtmoTable
        function AtmoSelect(app, event)
            %{
            if size(app.AtmoTable.Selection, 1) ~= 1
                return;
            elseif ismember(app.AtmoTable.Selection(1), 1 : size(app.atmo, 2))
                index = app.AtmoTable.Selection(1);
            else
                return;
            end
            app.AtmoTableRefreshDisp();
            %}
        end

        % Value changed function: AtmoPressureEditField
        function AtmoPressureSet(app, event)
            app.pressure_at = app.AtmoPressureEditField.Value;
        end

        % Value changed function: AtmoTemperatureEditField
        function AtmoTemperatureSet(app, event)
            app.temperature_at = app.AtmoTemperatureEditField.Value;
        end

        % Button pushed function: ExecuteButton
        function Execute(app, event)
            clc
            prop = app.PhaseCombine(app.prop, app.prop_ratio);

            d_cc = app.d_cc;
            d_th = app.d_th;
            d_ex = app.d_ex;
            A_cc = app.A_cc;
            A_th = app.A_th;
            A_ex = app.A_ex;
            Q_cc = app.Q_cc;

            atmo = app.PhaseCopy(app.atmo);
            temperature_in = app.temperature_in;
            pressure_in = app.pressure_in;
            temperature_at = app.temperature_at;
            pressure_at = app.pressure_at;

            app.Injection(prop, temperature_in, pressure_in);

            
        end

        % Value changed function: InjectionPressureEditField
        function InjectionPressureSet(app, event)
            value = app.InjectionPressureEditField.Value;
            
        end

        % Value changed function: InjectionTemperatureEditField
        function InjectionTemperatureSet(app, event)
            value = app.InjectionTemperatureEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu_2 = uimenu(app.FileMenu);
            app.NewMenu_2.Text = 'New';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Text = 'Save';
            app.SaveasMenu = uimenu(app.FileMenu);
            app.SaveasMenu.Text = 'Save as';
            app.ImportMenu = uimenu(app.FileMenu);
            app.ImportMenu.Text = 'Import';
            app.PropellantMenu = uimenu(app.ImportMenu);
            app.PropellantMenu.Text = 'Propellant';
            app.PropellantmixtureMenu = uimenu(app.ImportMenu);
            app.PropellantmixtureMenu.Text = 'Propellant mixture';
            app.EnginedefinitionMenu = uimenu(app.ImportMenu);
            app.EnginedefinitionMenu.Text = 'Engine definition';
            app.AtmosphericcompositionMenu = uimenu(app.ImportMenu);
            app.AtmosphericcompositionMenu.Text = 'Atmospheric composition';
            app.UnitdefinitionsMenu = uimenu(app.ImportMenu);
            app.UnitdefinitionsMenu.Text = 'Unit definitions';
            app.ExportMenu = uimenu(app.FileMenu);
            app.ExportMenu.Text = 'Export';
            app.PropellantMenu_2 = uimenu(app.ExportMenu);
            app.PropellantMenu_2.Text = 'Propellant';
            app.PropellantmixtureMenu_2 = uimenu(app.ExportMenu);
            app.PropellantmixtureMenu_2.Text = 'Propellant mixture';
            app.GeometrydefinitionsMenu_2 = uimenu(app.ExportMenu);
            app.GeometrydefinitionsMenu_2.Text = 'Geometry definitions';
            app.AtmosphericcompositionMenu_2 = uimenu(app.ExportMenu);
            app.AtmosphericcompositionMenu_2.Text = 'Atmospheric composition';
            app.UnitdefinitionsMenu_2 = uimenu(app.ExportMenu);
            app.UnitdefinitionsMenu_2.Text = 'Unit definitions';
            app.UnitsMenu = uimenu(app.UIFigure);
            app.UnitsMenu.Text = 'Units';
            app.LengthMenu = uimenu(app.UnitsMenu);
            app.LengthMenu.Text = 'Length';
            app.mMenu = uimenu(app.LengthMenu);
            app.mMenu.Text = 'm';
            app.mmMenu = uimenu(app.LengthMenu);
            app.mmMenu.Text = 'mm';
            app.inMenu = uimenu(app.LengthMenu);
            app.inMenu.Text = 'in';
            app.ftMenu = uimenu(app.LengthMenu);
            app.ftMenu.Text = 'ft';
            app.MassMenu = uimenu(app.UnitsMenu);
            app.MassMenu.Text = 'Mass';
            app.kgMenu = uimenu(app.MassMenu);
            app.kgMenu.Text = 'kg';
            app.gMenu = uimenu(app.MassMenu);
            app.gMenu.Text = 'g';
            app.tMenu = uimenu(app.MassMenu);
            app.tMenu.Text = 't';
            app.lbMenu = uimenu(app.MassMenu);
            app.lbMenu.Text = 'lb';
            app.SpeedMenu = uimenu(app.UnitsMenu);
            app.SpeedMenu.Text = 'Speed';
            app.msMenu = uimenu(app.SpeedMenu);
            app.msMenu.Text = 'm/s';
            app.kmhMenu = uimenu(app.SpeedMenu);
            app.kmhMenu.Text = 'km/h';
            app.ftsMenu = uimenu(app.SpeedMenu);
            app.ftsMenu.Text = 'ft/s';
            app.imhMenu = uimenu(app.SpeedMenu);
            app.imhMenu.Text = 'im/h';
            app.ForceMenu = uimenu(app.UnitsMenu);
            app.ForceMenu.Text = 'Force';
            app.PressureMenu = uimenu(app.UnitsMenu);
            app.PressureMenu.Text = 'Pressure';
            app.TemperatureMenu = uimenu(app.UnitsMenu);
            app.TemperatureMenu.Text = 'Temperature';
            app.EnergyMenu = uimenu(app.UnitsMenu);
            app.EnergyMenu.Text = 'Energy';
            app.PowerMenu = uimenu(app.UnitsMenu);
            app.PowerMenu.Text = 'Power';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.EngineDefinitionsPanel = uipanel(app.GridLayout);
            app.EngineDefinitionsPanel.Title = 'Engine Definitions';
            app.GridLayout2 = uigridlayout(app.EngineDefinitionsPanel);
            app.GridLayout6 = uigridlayout(app.GridLayout2);
            app.DiametersLabel = uilabel(app.GridLayout6);
            app.DiametersLabel.Text = 'Diameters';
            app.ChamberEditFieldLabel = uilabel(app.GridLayout6);
            app.ChamberEditFieldLabel.Text = 'Chamber';
            app.ChamberDiameterEditField = uieditfield(app.GridLayout6, 'numeric');
            app.ChamberDiameterEditField.ValueChangedFcn = createCallbackFcn(app, @ChamberDiameterSet, true);
            app.ThroatEditFieldLabel = uilabel(app.GridLayout6);
            app.ThroatEditFieldLabel.Text = 'Throat';
            app.ThroatDiameterEditField = uieditfield(app.GridLayout6, 'numeric');
            app.ThroatDiameterEditField.ValueChangedFcn = createCallbackFcn(app, @ThroatDiameterSet, true);
            app.NozzleExitEditFieldLabel = uilabel(app.GridLayout6);
            app.NozzleExitEditFieldLabel.Text = 'Nozzle Exit';
            app.NozzleDiameterEditField = uieditfield(app.GridLayout6, 'numeric');
            app.NozzleDiameterEditField.ValueChangedFcn = createCallbackFcn(app, @ExitDiameterSet, true);
            app.ChamberDiameterUnitLabel = uilabel(app.GridLayout6);
            app.ChamberDiameterUnitLabel.Text = 'm';
            app.ThroatDiameterUnitLabel = uilabel(app.GridLayout6);
            app.ThroatDiameterUnitLabel.Text = 'm';
            app.NozzleDiameterUnitLabel = uilabel(app.GridLayout6);
            app.NozzleDiameterUnitLabel.Text = 'm';
            app.GridLayout6_2 = uigridlayout(app.GridLayout2);
            app.AreasLabel = uilabel(app.GridLayout6_2);
            app.AreasLabel.Text = 'Areas';
            app.ChamberEditField_2Label = uilabel(app.GridLayout6_2);
            app.ChamberEditField_2Label.Text = 'Chamber';
            app.ChamberAreaEditField = uieditfield(app.GridLayout6_2, 'numeric');
            app.ChamberAreaEditField.ValueChangedFcn = createCallbackFcn(app, @ChamberAreaSet, true);
            app.ThroatEditField_2Label = uilabel(app.GridLayout6_2);
            app.ThroatEditField_2Label.Text = 'Throat';
            app.ThroatAreaEditField = uieditfield(app.GridLayout6_2, 'numeric');
            app.ThroatAreaEditField.ValueChangedFcn = createCallbackFcn(app, @ThroatAreaSet, true);
            app.NozzleExitEditField_2Label = uilabel(app.GridLayout6_2);
            app.NozzleExitEditField_2Label.Text = 'Nozzle Exit';
            app.NozzleAreaEditField = uieditfield(app.GridLayout6_2, 'numeric');
            app.NozzleAreaEditField.ValueChangedFcn = createCallbackFcn(app, @ExitAreaSet, true);
            app.ChamberAreaUnitLabel = uilabel(app.GridLayout6_2);
            app.ChamberAreaUnitLabel.Text = 'm<sup>2</sup>';
            app.ThroatAreaUnitLabel = uilabel(app.GridLayout6_2);
            app.ThroatAreaUnitLabel.Text = 'm<sup>2</sup>';
            app.NozzleAreaUnitLabel = uilabel(app.GridLayout6_2);
            app.NozzleAreaUnitLabel.Text = 'm<sup>2</sup>';
            app.GridLayout6_3 = uigridlayout(app.GridLayout2);
            app.ChamberDefinitionsLabel = uilabel(app.GridLayout6_3);
            app.ChamberDefinitionsLabel.Text = 'Chamber Definitions';
            app.HeatingElementOutputEditFieldLabel = uilabel(app.GridLayout6_3);
            app.HeatingElementOutputEditFieldLabel.Text = 'Heating Element Output';
            app.HeatingElementOutputEditField = uieditfield(app.GridLayout6_3, 'numeric');
            app.HeatingElementOutputEditField.ValueChangedFcn = createCallbackFcn(app, @HeatingElementOutputSet, true);
            app.PressureEditField_2Label = uilabel(app.GridLayout6_3);
            app.PressureEditField_2Label.Text = 'Pressure';
            app.ChamberPressureEditField = uieditfield(app.GridLayout6_3, 'numeric');
            app.ChamberPressureEditField.ValueChangedFcn = createCallbackFcn(app, @ChamberPressureSet, true);
            app.HeatingElementOutputUnitLabel = uilabel(app.GridLayout6_3);
            app.HeatingElementOutputUnitLabel.Text = 'W';
            app.ChamberPressureUnitLabel = uilabel(app.GridLayout6_3);
            app.ChamberPressureUnitLabel.Text = 'Pa';
            app.GridLayout6_5 = uigridlayout(app.GridLayout2);
            app.InjectionDefinitionsLabel = uilabel(app.GridLayout6_5);
            app.InjectionDefinitionsLabel.Text = 'Injection Definitions';
            app.TemperatureEditFieldLabel_2 = uilabel(app.GridLayout6_5);
            app.TemperatureEditFieldLabel_2.Text = 'Temperature';
            app.InjectionTemperatureEditField = uieditfield(app.GridLayout6_5, 'numeric');
            app.InjectionTemperatureEditField.ValueChangedFcn = createCallbackFcn(app, @InjectionTemperatureSet, true);
            app.PressureEditField_2Label_2 = uilabel(app.GridLayout6_5);
            app.PressureEditField_2Label_2.Text = 'Pressure';
            app.InjectionPressureEditField = uieditfield(app.GridLayout6_5, 'numeric');
            app.InjectionPressureEditField.ValueChangedFcn = createCallbackFcn(app, @InjectionPressureSet, true);
            app.InjectionTemperatureUnitLabel = uilabel(app.GridLayout6_5);
            app.InjectionTemperatureUnitLabel.Text = 'K';
            app.InjectionPressureUnitLabel = uilabel(app.GridLayout6_5);
            app.InjectionPressureUnitLabel.Text = 'Pa';
            app.AtmospherePanel = uipanel(app.GridLayout);
            app.AtmospherePanel.Title = 'Atmosphere';
            app.GridLayout2_2 = uigridlayout(app.AtmospherePanel);
            app.GridLayout6_4 = uigridlayout(app.GridLayout2_2);
            app.AtmoPressureUnitLabel = uilabel(app.GridLayout6_4);
            app.AtmoPressureUnitLabel.Text = 'Pa';
            app.AtmoTemperatureUnitLabel = uilabel(app.GridLayout6_4);
            app.AtmoTemperatureUnitLabel.Text = 'K';
            app.ThermodynamicInfoLabel = uilabel(app.GridLayout6_4);
            app.ThermodynamicInfoLabel.Text = 'Thermodynamic Info';
            app.PressureEditFieldLabel = uilabel(app.GridLayout6_4);
            app.PressureEditFieldLabel.Text = 'Pressure';
            app.AtmoPressureEditField = uieditfield(app.GridLayout6_4, 'numeric');
            app.AtmoPressureEditField.ValueChangedFcn = createCallbackFcn(app, @AtmoPressureSet, true);
            app.TemperatureEditFieldLabel = uilabel(app.GridLayout6_4);
            app.TemperatureEditFieldLabel.Text = 'Temperature';
            app.AtmoTemperatureEditField = uieditfield(app.GridLayout6_4, 'numeric');
            app.AtmoTemperatureEditField.ValueChangedFcn = createCallbackFcn(app, @AtmoTemperatureSet, true);
            app.GridLayout7 = uigridlayout(app.GridLayout2_2);
            app.AtmoTable = uitable(app.GridLayout7);
            app.AtmoTable.ColumnName = {'Species'; 'Mixture Ratio'};
            app.AtmoTable.RowName = {};
            app.AtmoTable.ColumnEditable = [true true];
            app.AtmoTable.CellEditCallback = createCallbackFcn(app, @AtmoEdit, true);
            app.AtmoTable.CellSelectionCallback = createCallbackFcn(app, @AtmoSelect, true);
            app.AtmoTable.Multiselect = 'off';
            app.GridLayout9_2 = uigridlayout(app.GridLayout7);
            app.AddAtmoButton = uibutton(app.GridLayout9_2, 'push');
            app.AddAtmoButton.ButtonPushedFcn = createCallbackFcn(app, @AtmoAdd, true);
            app.AddAtmoButton.Text = 'Add Species';
            app.RemoveAtmoButton = uibutton(app.GridLayout9_2, 'push');
            app.RemoveAtmoButton.ButtonPushedFcn = createCallbackFcn(app, @AtmoRemove, true);
            app.RemoveAtmoButton.Text = 'Remove Species';
            app.MoveUpAtmoButton = uibutton(app.GridLayout9_2, 'push');
            app.MoveUpAtmoButton.ButtonPushedFcn = createCallbackFcn(app, @AtmoMoveUp, true);
            app.MoveUpAtmoButton.Text = 'Move Up';
            app.MoveDownAtmoButton = uibutton(app.GridLayout9_2, 'push');
            app.MoveDownAtmoButton.ButtonPushedFcn = createCallbackFcn(app, @AtmoMoveDown, true);
            app.MoveDownAtmoButton.Text = 'Move Down';
            app.ConfirmAtmoButton = uibutton(app.GridLayout9_2, 'push');
            app.ConfirmAtmoButton.ButtonPushedFcn = createCallbackFcn(app, @AtmoConfirm, true);
            app.ConfirmAtmoButton.Text = 'Confirm changes';
            app.CompositionLabel = uilabel(app.GridLayout7);
            app.CompositionLabel.Text = 'Composition';
            app.PropellantsPanel = uipanel(app.GridLayout);
            app.PropellantsPanel.Title = 'Propellants';
            app.GridLayout3 = uigridlayout(app.PropellantsPanel);
            app.GridLayout10 = uigridlayout(app.GridLayout3);
            app.PropTable = uitable(app.GridLayout10);
            app.PropTable.ColumnName = {'Propellant'; 'Mixture Ratio'};
            app.PropTable.RowName = {};
            app.PropTable.ColumnEditable = [true true true];
            app.PropTable.CellEditCallback = createCallbackFcn(app, @PropEdit, true);
            app.PropTable.CellSelectionCallback = createCallbackFcn(app, @PropSelect, true);
            app.PropTable.Multiselect = 'off';
            app.GridLayout8 = uigridlayout(app.GridLayout10);
            app.RemovePropButton = uibutton(app.GridLayout8, 'push');
            app.RemovePropButton.ButtonPushedFcn = createCallbackFcn(app, @PropRemove, true);
            app.RemovePropButton.Text = 'Remove Propellant';
            app.MoveUpPropButton = uibutton(app.GridLayout8, 'push');
            app.MoveUpPropButton.ButtonPushedFcn = createCallbackFcn(app, @PropMoveUp, true);
            app.MoveUpPropButton.Text = 'Move Up';
            app.MoveDownPropButton = uibutton(app.GridLayout8, 'push');
            app.MoveDownPropButton.ButtonPushedFcn = createCallbackFcn(app, @PropMoveDown, true);
            app.MoveDownPropButton.Text = 'Move Down';
            app.AddPropButton = uibutton(app.GridLayout8, 'push');
            app.AddPropButton.ButtonPushedFcn = createCallbackFcn(app, @PropAdd, true);
            app.AddPropButton.Text = 'Add New';
            app.MixturecompositionLabel = uilabel(app.GridLayout10);
            app.MixturecompositionLabel.Text = 'Mixture composition';
            app.GridLayout11 = uigridlayout(app.GridLayout3);
            app.PropellantLabel = uilabel(app.GridLayout11);
            app.PropellantLabel.Text = 'Propellant';
            app.GridLayout9 = uigridlayout(app.GridLayout11);
            app.AddSpeciesButton = uibutton(app.GridLayout9, 'push');
            app.AddSpeciesButton.ButtonPushedFcn = createCallbackFcn(app, @SpeciesAdd, true);
            app.AddSpeciesButton.Text = 'Add Species';
            app.RemoveSpeciesButton_2 = uibutton(app.GridLayout9, 'push');
            app.RemoveSpeciesButton_2.ButtonPushedFcn = createCallbackFcn(app, @SpeciesRemove, true);
            app.RemoveSpeciesButton_2.Text = 'Remove Species';
            app.MoveDownSpeciesButton = uibutton(app.GridLayout9, 'push');
            app.MoveDownSpeciesButton.ButtonPushedFcn = createCallbackFcn(app, @SpeciesMoveDown, true);
            app.MoveDownSpeciesButton.Text = 'Move Down';
            app.MoveUpSpeciesButton = uibutton(app.GridLayout9, 'push');
            app.MoveUpSpeciesButton.ButtonPushedFcn = createCallbackFcn(app, @SpeciesMoveUp, true);
            app.MoveUpSpeciesButton.Text = 'Move Up';
            app.ConfirmSpeciesButton = uibutton(app.GridLayout9, 'push');
            app.ConfirmSpeciesButton.ButtonPushedFcn = createCallbackFcn(app, @SpeciesConfirm, true);
            app.ConfirmSpeciesButton.Text = 'Confirm changes';
            app.SpeciesTable = uitable(app.GridLayout11);
            app.SpeciesTable.ColumnName = {'Species'; 'Mixture Ratio'};
            app.SpeciesTable.RowName = {};
            app.SpeciesTable.ColumnEditable = [true true];
            app.SpeciesTable.CellEditCallback = createCallbackFcn(app, @SpeciesEdit, true);
            app.SpeciesTable.CellSelectionCallback = createCallbackFcn(app, @SpeciesSelect, true);
            app.SpeciesTable.Multiselect = 'off';
            app.OutputsPanel = uipanel(app.GridLayout);
            app.OutputsPanel.Title = 'Outputs';
            app.GridLayout4 = uigridlayout(app.OutputsPanel);
            app.ExecuteButton = uibutton(app.GridLayout4, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @Execute, true);
            app.ExecuteButton.Text = 'Execute';
            app.TabGroup = uitabgroup(app.GridLayout4);
            app.SummaryTab = uitab(app.TabGroup);
            app.SummaryTab.Title = 'Summary';
            app.GraphingTab = uitab(app.TabGroup);
            app.GraphingTab.Title = 'Graphing';
            app.ResidualsPanel = uipanel(app.GridLayout);
            app.ResidualsPanel.Title = 'Residuals';
            app.GridLayout5 = uigridlayout(app.ResidualsPanel);
            app.ThroatPressureEditFieldLabel = uilabel(app.GridLayout5);
            app.ThroatPressureEditFieldLabel.Text = 'Throat Pressure';
            app.ThroatPressureEditField = uieditfield(app.GridLayout5, 'numeric');
            app.ThroatPressureEditField.Limits = [0 1];
            app.ThroatPressureEditField.Value = 1e-06;
            app.ThroatPressureEditFieldLabel_2 = uilabel(app.GridLayout5);
            app.ThroatPressureEditFieldLabel_2.Text = 'Expansion Ratio';
            app.ThroatPressureEditField_2 = uieditfield(app.GridLayout5, 'numeric');
            app.ThroatPressureEditField_2.Limits = [0 1];
            app.ThroatPressureEditField_2.Value = 1e-06;
            app.ThroatPressureEditFieldLabel_3 = uilabel(app.GridLayout5);
            app.ThroatPressureEditFieldLabel_3.Text = 'Throat Pressure';
            app.ThroatPressureEditField_3 = uieditfield(app.GridLayout5, 'numeric');
            app.ThroatPressureEditField_3.Limits = [0 1];
            app.ThroatPressureEditField_3.Value = 1e-06;
            app.ThroatPressureEditFieldLabel_4 = uilabel(app.GridLayout5);
            app.ThroatPressureEditFieldLabel_4.Text = 'Throat Pressure';
            app.ThroatPressureEditField_4 = uieditfield(app.GridLayout5, 'numeric');
            app.ThroatPressureEditField_4.Limits = [0 1];
            app.ThroatPressureEditField_4.Value = 1e-06;
            app.ThroatPressureEditFieldLabel_5 = uilabel(app.GridLayout5);
            app.ThroatPressureEditFieldLabel_5.Text = 'Throat Pressure';
            app.ThroatPressureEditField_5 = uieditfield(app.GridLayout5, 'numeric');
            app.ThroatPressureEditField_5.Limits = [0 1];
            app.ThroatPressureEditField_5.Value = 1e-06;
            app.DEBUGButton = uibutton(app.GridLayout5, 'push');
            app.DEBUGButton.ButtonPushedFcn = createCallbackFcn(app, @DEBUG, true);
            app.DEBUGButton.Text = 'DEBUG';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.NewMenu = uimenu(app.ContextMenu);
            app.NewMenu.Text = 'New';
            app.PredefinedMenu = uimenu(app.ContextMenu);
            app.PredefinedMenu.Text = 'Pre-defined';
            app.Menu = uimenu(app.PredefinedMenu);
            app.Menu.Text = 'Menu';
            % Assign app.ContextMenu
            app.AddAtmoButton.ContextMenu = app.ContextMenu;
            app.ConfirmAtmoButton.ContextMenu = app.ContextMenu;
            app.AddPropButton.ContextMenu = app.ContextMenu;
            app.AddSpeciesButton.ContextMenu = app.ContextMenu;
            app.ConfirmSpeciesButton.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartUp(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end